classdef ADCmapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ADCmappUIFigure             matlab.ui.Figure
        Hyperlink                   matlab.ui.control.Hyperlink
        TabGroup4                   matlab.ui.container.TabGroup
        SequenceParametersTab       matlab.ui.container.Tab
        DicomDirViewField           matlab.ui.control.EditField
        DicomDirViewFieldLabel      matlab.ui.control.Label
        AspectRatioViewField        matlab.ui.control.NumericEditField
        AspectRatioViewFieldLabel   matlab.ui.control.Label
        SLTViewField                matlab.ui.control.NumericEditField
        SLTViewFieldLabel           matlab.ui.control.Label
        FOVViewField                matlab.ui.control.NumericEditField
        FOVViewFieldLabel           matlab.ui.control.Label
        NRImagesViewField           matlab.ui.control.NumericEditField
        NRImagesViewFieldLabel      matlab.ui.control.Label
        SlicesViewField             matlab.ui.control.NumericEditField
        SlicesViewFieldLabel        matlab.ui.control.Label
        dimyViewField               matlab.ui.control.NumericEditField
        dimyViewFieldLabel          matlab.ui.control.Label
        dimxViewField               matlab.ui.control.NumericEditField
        dimxViewFieldLabel          matlab.ui.control.Label
        TEViewField                 matlab.ui.control.NumericEditField
        TEViewFieldLabel            matlab.ui.control.Label
        TRViewField                 matlab.ui.control.NumericEditField
        TRViewFieldLabel            matlab.ui.control.Label
        TabGroup2                   matlab.ui.container.TabGroup
        RawImagesTab                matlab.ui.container.Tab
        GradViewField               matlab.ui.control.EditField
        GradViewFieldLabel          matlab.ui.control.Label
        BvalueViewField             matlab.ui.control.EditField
        BvalueViewFieldLabel        matlab.ui.control.Label
        RawImage                    matlab.ui.control.Image
        ImageWindowLevelPanel       matlab.ui.container.Panel
        AutoLWButton                matlab.ui.control.StateButton
        WindowEditFieldLabel        matlab.ui.control.Label
        LevelEditFieldLabel         matlab.ui.control.Label
        LevelSlider                 matlab.ui.control.Slider
        LevelEditField              matlab.ui.control.NumericEditField
        WindowSlider                matlab.ui.control.Slider
        WindowEditField             matlab.ui.control.NumericEditField
        MaskSettingsPanel           matlab.ui.container.Panel
        ThresholdEditField          matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel     matlab.ui.control.Label
        MaskSizeEditField           matlab.ui.control.NumericEditField
        UndoButton                  matlab.ui.control.Button
        OpenButton                  matlab.ui.control.Button
        CloseButton                 matlab.ui.control.Button
        DilateButton                matlab.ui.control.Button
        ErodeButton                 matlab.ui.control.Button
        ThresholdSlider             matlab.ui.control.Slider
        ImageSelectionPanel         matlab.ui.container.Panel
        ImageEditFieldLabel         matlab.ui.control.Label
        SlicesEditFieldLabel        matlab.ui.control.Label
        ImageSlider                 matlab.ui.control.Slider
        ImageEditField              matlab.ui.control.NumericEditField
        SlicesSlider                matlab.ui.control.Slider
        SlicesEditField             matlab.ui.control.NumericEditField
        ImageLabel                  matlab.ui.control.Label
        RawDataFig                  matlab.ui.control.UIAxes
        ReconstructionTab           matlab.ui.container.Tab
        EPILabel                    matlab.ui.control.Label
        EPIregistrationCheckBox     matlab.ui.control.CheckBox
        EPIdistortionCheckBox       matlab.ui.control.CheckBox
        EPIPOCSCheckBox             matlab.ui.control.CheckBox
        EPIRampSamplingCheckBox     matlab.ui.control.CheckBox
        FitParametersTab            matlab.ui.container.Tab
        UserGradientTablePanel      matlab.ui.container.Panel
        ImportGradTableButton       matlab.ui.control.Button
        BvalueGradientDirectionSelectionPanel  matlab.ui.container.Panel
        CheckBox_64                 matlab.ui.control.CheckBox
        CheckBox_63                 matlab.ui.control.CheckBox
        CheckBox_62                 matlab.ui.control.CheckBox
        CheckBox_61                 matlab.ui.control.CheckBox
        CheckBox_60                 matlab.ui.control.CheckBox
        CheckBox_59                 matlab.ui.control.CheckBox
        CheckBox_58                 matlab.ui.control.CheckBox
        CheckBox_57                 matlab.ui.control.CheckBox
        CheckBox_56                 matlab.ui.control.CheckBox
        CheckBox_55                 matlab.ui.control.CheckBox
        CheckBox_54                 matlab.ui.control.CheckBox
        CheckBox_53                 matlab.ui.control.CheckBox
        CheckBox_52                 matlab.ui.control.CheckBox
        CheckBox_51                 matlab.ui.control.CheckBox
        CheckBox_50                 matlab.ui.control.CheckBox
        CheckBox_49                 matlab.ui.control.CheckBox
        CheckBox_48                 matlab.ui.control.CheckBox
        CheckBox_47                 matlab.ui.control.CheckBox
        CheckBox_46                 matlab.ui.control.CheckBox
        CheckBox_45                 matlab.ui.control.CheckBox
        CheckBox_44                 matlab.ui.control.CheckBox
        CheckBox_43                 matlab.ui.control.CheckBox
        CheckBox_42                 matlab.ui.control.CheckBox
        CheckBox_41                 matlab.ui.control.CheckBox
        CheckBox_40                 matlab.ui.control.CheckBox
        CheckBox_39                 matlab.ui.control.CheckBox
        CheckBox_38                 matlab.ui.control.CheckBox
        CheckBox_37                 matlab.ui.control.CheckBox
        CheckBox_36                 matlab.ui.control.CheckBox
        CheckBox_35                 matlab.ui.control.CheckBox
        CheckBox_34                 matlab.ui.control.CheckBox
        CheckBox_33                 matlab.ui.control.CheckBox
        CheckBox_32                 matlab.ui.control.CheckBox
        CheckBox_31                 matlab.ui.control.CheckBox
        CheckBox_30                 matlab.ui.control.CheckBox
        CheckBox_29                 matlab.ui.control.CheckBox
        CheckBox_28                 matlab.ui.control.CheckBox
        CheckBox_27                 matlab.ui.control.CheckBox
        CheckBox_26                 matlab.ui.control.CheckBox
        CheckBox_25                 matlab.ui.control.CheckBox
        CheckBox_24                 matlab.ui.control.CheckBox
        CheckBox_23                 matlab.ui.control.CheckBox
        CheckBox_22                 matlab.ui.control.CheckBox
        CheckBox_21                 matlab.ui.control.CheckBox
        CheckBox_20                 matlab.ui.control.CheckBox
        CheckBox_19                 matlab.ui.control.CheckBox
        CheckBox_18                 matlab.ui.control.CheckBox
        CheckBox_17                 matlab.ui.control.CheckBox
        CheckBox_16                 matlab.ui.control.CheckBox
        CheckBox_15                 matlab.ui.control.CheckBox
        CheckBox_14                 matlab.ui.control.CheckBox
        CheckBox_13                 matlab.ui.control.CheckBox
        CheckBox_12                 matlab.ui.control.CheckBox
        CheckBox_11                 matlab.ui.control.CheckBox
        CheckBox_10                 matlab.ui.control.CheckBox
        CheckBox_9                  matlab.ui.control.CheckBox
        CheckBox_8                  matlab.ui.control.CheckBox
        CheckBox_7                  matlab.ui.control.CheckBox
        CheckBox_6                  matlab.ui.control.CheckBox
        CheckBox_5                  matlab.ui.control.CheckBox
        CheckBox_4                  matlab.ui.control.CheckBox
        CheckBox_3                  matlab.ui.control.CheckBox
        CheckBox_2                  matlab.ui.control.CheckBox
        CheckBox_1                  matlab.ui.control.CheckBox
        GoodnessOfFitPanel          matlab.ui.container.Panel
        ThresholdLabel              matlab.ui.control.Label
        RsquareLabel                matlab.ui.control.Label
        Threshold                   matlab.ui.control.CheckBox
        Rsquare                     matlab.ui.control.NumericEditField
        SliceSelectionPanel         matlab.ui.container.Panel
        LastSliceEditField          matlab.ui.control.NumericEditField
        FirstSliceEditField         matlab.ui.control.NumericEditField
        LastSliceEditFieldLabel     matlab.ui.control.Label
        FirstSliceEditFieldLabel    matlab.ui.control.Label
        AppSettingsTab              matlab.ui.container.Tab
        ColorsButtonGroup           matlab.ui.container.ButtonGroup
        BlackWhiteButton            matlab.ui.control.RadioButton
        ClassicButton               matlab.ui.control.RadioButton
        ColorsLabel                 matlab.ui.control.Label
        AutoSoundsCheckBoxLabel     matlab.ui.control.Label
        AutoSoundsCheckBox          matlab.ui.control.CheckBox
        SoundsLabel                 matlab.ui.control.Label
        TabGroup3                   matlab.ui.container.TabGroup
        ADCmapTab                   matlab.ui.container.Tab
        ADCexportRoiDataButton      matlab.ui.control.Button
        LinearLabel                 matlab.ui.control.Label
        LogLabel                    matlab.ui.control.Label
        ADCBrushEditField           matlab.ui.control.NumericEditField
        ADCBrushEditFieldLabel      matlab.ui.control.Label
        ADCEraseButton              matlab.ui.control.Button
        ADCROIbutton                matlab.ui.control.Button
        ADCunitLabel3               matlab.ui.control.Label
        ADCunitLabel2               matlab.ui.control.Label
        ADCunitLabel1               matlab.ui.control.Label
        ADCViewFieldLabel           matlab.ui.control.Label
        ADCViewField                matlab.ui.control.NumericEditField
        YViewField                  matlab.ui.control.NumericEditField
        YViewFieldLabel             matlab.ui.control.Label
        XViewField                  matlab.ui.control.NumericEditField
        XViewFieldLabel             matlab.ui.control.Label
        ADCImage                    matlab.ui.control.Image
        ADCColormapButtonGroup      matlab.ui.container.ButtonGroup
        ADCScaleAutoButton          matlab.ui.control.Button
        ADCScaleMinEditField        matlab.ui.control.NumericEditField
        ADCScaleMinLabel            matlab.ui.control.Label
        ADCScaleMaxLabel            matlab.ui.control.Label
        ADCScaleEditField           matlab.ui.control.NumericEditField
        naviaButton                 matlab.ui.control.RadioButton
        parulaButton                matlab.ui.control.RadioButton
        winterButton                matlab.ui.control.RadioButton
        autumnButton                matlab.ui.control.RadioButton
        grayButton                  matlab.ui.control.RadioButton
        ADCImageSelectionPanel      matlab.ui.container.Panel
        ADCMapSlicesEditField       matlab.ui.control.NumericEditField
        ADCMapSlicesEditFieldLabel  matlab.ui.control.Label
        ADCMapSlicesSlider          matlab.ui.control.Slider
        ADCMapLabel                 matlab.ui.control.Label
        ADClinearPlot               matlab.ui.control.UIAxes
        ADClogPlot                  matlab.ui.control.UIAxes
        ADCMapFig                   matlab.ui.control.UIAxes
        M0R2Tab                     matlab.ui.container.Tab
        M0BrushEditField            matlab.ui.control.NumericEditField
        M0BrushEditFieldLabel       matlab.ui.control.Label
        M0EraseButton               matlab.ui.control.Button
        M0ROIbutton                 matlab.ui.control.Button
        R2Image                     matlab.ui.control.Image
        M0Image                     matlab.ui.control.Image
        R2ColorMapButtonGroup       matlab.ui.container.ButtonGroup
        naviaButton3                matlab.ui.control.RadioButton
        parulaButton3               matlab.ui.control.RadioButton
        winterButton3               matlab.ui.control.RadioButton
        autumnButton3               matlab.ui.control.RadioButton
        grayButton3                 matlab.ui.control.RadioButton
        M0ColorMapButtonGroup       matlab.ui.container.ButtonGroup
        naviaButton2                matlab.ui.control.RadioButton
        parulaButton2               matlab.ui.control.RadioButton
        winterButton2               matlab.ui.control.RadioButton
        autumnButton2               matlab.ui.control.RadioButton
        grayButton2                 matlab.ui.control.RadioButton
        M0ImageSelectionPanel       matlab.ui.container.Panel
        M0MapSlicesEditField        matlab.ui.control.NumericEditField
        M0MapSlicesEditFieldLabel   matlab.ui.control.Label
        M0MapSlicesSlider           matlab.ui.control.Slider
        R2MapLabel                  matlab.ui.control.Label
        M0MapLabel                  matlab.ui.control.Label
        R2MapFig                    matlab.ui.control.UIAxes
        M0MapFig                    matlab.ui.control.UIAxes
        TabGroup1                   matlab.ui.container.TabGroup
        DashboardTab                matlab.ui.container.Tab
        EstimatedFitTimeViewField   matlab.ui.control.EditField
        EstimatedRegTimeViewField   matlab.ui.control.EditField
        AbortRegButton              matlab.ui.control.Button
        RegistrationDropDown        matlab.ui.control.DropDown
        RegProgressLabel            matlab.ui.control.Label
        RegProgressGauge            matlab.ui.control.LinearGauge
        Lamp2                       matlab.ui.control.Lamp
        RegisterButton              matlab.ui.control.Button
        AbortFitButton              matlab.ui.control.Button
        GIFButton                   matlab.ui.control.Button
        Lamp6                       matlab.ui.control.Lamp
        FitProgressLabel            matlab.ui.control.Label
        DCMNIFTIButton              matlab.ui.control.Button
        ExitButton                  matlab.ui.control.Button
        Lamp5                       matlab.ui.control.Lamp
        FitProgressGauge            matlab.ui.control.LinearGauge
        Lamp4                       matlab.ui.control.Lamp
        FitADCButton                matlab.ui.control.Button
        Lamp3                       matlab.ui.control.Lamp
        MaskButton                  matlab.ui.control.Button
        Lamp1                       matlab.ui.control.Lamp
        LoadButton                  matlab.ui.control.Button
        MRSImage                    matlab.ui.control.Image
        StatusLabel                 matlab.ui.control.Label
        StatusImage                 matlab.ui.control.Image
        BackgroundBox               matlab.ui.control.TextArea
        MessageBox                  matlab.ui.control.TextArea
        TulpLogo                    matlab.ui.control.Image
        Credits                     matlab.ui.control.Label
        ADCmappingLabel             matlab.ui.control.Label
    end


    % --------------------------------------------------------------------------------------
    %
    %   ADCmapp
    %
    %   ADC mapping of diffusion-weighted MR Solutions DICOM data
    %
    %   Gustav Strijkers
    %   Amsterdam UMC
    %   g.j.strijkers@amsterdamumc.nl
    %   September 2026
    %
    %   How the app is put together:
    %
    %     ADCmapp.mlapp + gui/   widgets, callbacks and plotting; gui/adapters holds
    %                            the only code that reads a widget or holds the app
    %     @adcData               the dataset the app holds as app.td: the DICOM
    %                            headers, the b-values, the images, the maps, and the
    %                            import that fills them
    %     +adcmapp/              import and export, registration, fitting, colour and
    %                            display helpers
    %     functions/             third party only
    %
    %   A series is held as (image, slice, x, y), one image per b-value and gradient
    %   direction, and the maps, the mask and the thresholds as (slice, x, y).
    %
    % --------------------------------------------------------------------------------------


    properties (Access = public)

        % App version
        appVersion = "3.0.2"        % App version

        % The dataset
        td                          % adcData, the data this session works on

        % Folders and closing
        appFolder                   % application folder
        oldFolder                   % folder MATLAB was in when the app started
        oldWarningState             % warning state MATLAB had when the app started
        busyFlag = false            % a step is running: status BUSY until OK or ERROR
        closeRequested = false      % the window was closed while a step was running
        closeTimer                  % timer that closes the app once that step has finished
        importFolder = ''           % folder the load button opens on
        gradFolder = ''             % gradient table folder
        dicomExportFolder = ''      % export folder chosen when no scanner folder is found
        mrdExportFolder = ''        % reconstruction folder chosen when no scanner study is found
        gifExportFolder = ''        % GIF export folder chosen when no scanner folder is found
        roiExportFolder = ''        % ROI data export folder

        % Flags
        elastixDetectedFlag = false % elastix can be run
        validDataFlag = false       % a valid dataset is loaded
        validRegFlag = false        % the images have been registered
        validFitFlag = false        % maps have been fitted
        roiFlag = false             % ROI drawing rather than cross-hair
        abortRegFlag = false        % abort the registration
        abortFitFlag = false        % abort the fit

        % Plots
        txt1                        % R-square colour bar label
        txt2                        % R-square colour bar label
        crossMD                     % cross-hair on the ADC map
        crossM0                     % cross-hair on the M0 map
        roiPoints = []              % ROI point coordinates (x, y, slice)
        roiMD                       % ROI drawing on the ADC map
        roiM0                       % ROI drawing on the M0 map
        datapoints = []             % signal under the cross-hair or ROI, one value per image
        maskBeforeOperation = []    % the mask before the last erode, dilate, close or open, for Undo
        maskAfterOperation = []     % the mask that operation left, so Undo can tell it is current

        % Colour maps
        adccmap                     % ADC colour map
        m0cmap                      % M0 colour map
        r2cmap                      % R-square colour map

        % Colours
        brightred                   % red
        red                         % red
        brightgreen                 % green
        green                       % green
        yellow                      % yellow
        orange                      % orange
        blue                        % blue
        lightblue                   % light blue
        grey                        % grey
        guiBlack                    % app black
        guiGrey                     % app grey
        guiMiddleGrey               % app middle grey
        guiDarkGrey                 % app dark grey
        guiWhite                    % app white
        guiBlackBlue                % app black/blueish

        % Sounds
        warningSound                % warning sound
        errorSound                  % error sound

        % Settings and log
        jsonPar = struct()          % settings, from adcmapp.io.readSettingsFile
        logFile = -1                % file id of this session's log, -1 when there is none
        logFileName = ''            % full path of this session's log

    end


    methods (Access = private)


        %------------------------------------------------
        %
        %  GUI layout
        %
        %------------------------------------------------


        % Traffic lights
        function SetLights(app,code)

            % Purpose:
            %   Colours the six lamps beside the dashboard buttons.
            %
            % How it works:
            %   0 is red, 1 is green, 3 is yellow for the step that is running,
            %   anything else leaves the lamp as it is.

            lamps = {app.Lamp1, app.Lamp2, app.Lamp3, app.Lamp4, app.Lamp5, app.Lamp6};

            for k = 1:numel(lamps)
                if code(k) == 0
                    lamps{k}.Color = app.red;
                elseif code(k) == 1
                    lamps{k}.Color = app.green;
                elseif code(k) == 3
                    lamps{k}.Color = app.yellow;
                end
            end

            drawnow;

        end


        % Buttons on/off
        function SetButtons(app,code)

            % Purpose:
            %   Enables or disables the seven dashboard buttons.
            %
            % How it works:
            %   One entry per button, in dashboard order: load, register, mask, fit,
            %   DICOM export, GIF export, exit. The first covers the load button and
            %   the EPI check boxes an MRD load reads, which are of use only while a
            %   load can be started.

            app.SetEnableFcn({app.LoadButton, app.EPIRampSamplingCheckBox, app.EPIPOCSCheckBox, ...
                app.EPIdistortionCheckBox, app.EPIregistrationCheckBox}, code(1) ~= 0);
            app.SetEnableFcn({app.RegisterButton, app.RegistrationDropDown}, code(2) ~= 0);
            app.SetEnableFcn({app.MaskButton}, code(3) ~= 0);
            app.SetEnableFcn({app.FitADCButton}, code(4) ~= 0);
            app.SetEnableFcn({app.DCMNIFTIButton}, code(5) ~= 0);
            app.SetEnableFcn({app.GIFButton}, code(6) ~= 0);
            app.SetEnableFcn({app.ExitButton}, code(7) ~= 0);

            drawnow;

        end


        % Enable or disable a set of widgets
        function SetEnableFcn(app, widgets, tf) %#ok<INUSD>

            % Purpose:
            %   Sets Enable on every widget in the list to the same state.

            state = 'off';
            if tf
                state = 'on';
            end

            for k = 1:numel(widgets)
                widgets{k}.Enable = state;
            end

        end


        % Set application colors
        function SetAppColorsFcn(app, guiColors)

            % Purpose:
            %   Applies one of the two colour schemes to every panel, label and axis.
            %
            % How it works:
            %   guiColors is backgroundColor, fontColor, boxColor, gaugeColor,
            %   gaugeBackgroundColor, figureColor, movieFigureColor, gridColor, and two
            %   colours this app does not use.

            backgroundColor = guiColors{1};
            fontColor = guiColors{2};
            gaugeColor = guiColors{4};
            gaugeBackgroundColor = guiColors{5};
            figureColor = guiColors{6};
            movieFigureColor = guiColors{7};

            % Panels
            panels = {app.DashboardTab, app.RawImagesTab, app.FitParametersTab, app.AppSettingsTab, ...
                app.SequenceParametersTab, app.ADCmapTab, app.M0R2Tab, app.ImageSelectionPanel, ...
                app.ADCImageSelectionPanel, app.ADCColormapButtonGroup, app.MaskSettingsPanel, ...
                app.ImageWindowLevelPanel, app.SliceSelectionPanel, app.GoodnessOfFitPanel, ...
                app.UserGradientTablePanel, app.BvalueGradientDirectionSelectionPanel, app.ColorsButtonGroup, ...
                app.M0ImageSelectionPanel, app.M0ColorMapButtonGroup, app.R2ColorMapButtonGroup, ...
                app.ReconstructionTab};

            app.ADCmappUIFigure.Color = backgroundColor;
            for k = 1:numel(panels)
                panels{k}.BackgroundColor = backgroundColor;
            end

            % Texts
            texts = {app.SlicesEditFieldLabel, app.ImageEditFieldLabel, app.ThresholdEditFieldLabel, ...
                app.WindowEditFieldLabel, app.LevelEditFieldLabel, app.GradViewFieldLabel, ...
                app.BvalueViewFieldLabel, app.FirstSliceEditFieldLabel, app.LastSliceEditFieldLabel, ...
                app.RsquareLabel, app.ThresholdLabel, app.AutoSoundsCheckBoxLabel, app.BlackWhiteButton, ...
                app.ClassicButton, app.DicomDirViewFieldLabel, app.TRViewFieldLabel, app.TEViewFieldLabel, ...
                app.NRImagesViewFieldLabel, app.AspectRatioViewFieldLabel, app.FOVViewFieldLabel, ...
                app.dimxViewFieldLabel, app.dimyViewFieldLabel, app.SlicesViewFieldLabel, ...
                app.SLTViewFieldLabel, app.ADCMapSlicesEditFieldLabel, app.ADCScaleMaxLabel, app.ADCScaleMinLabel, ...
                app.grayButton, app.autumnButton, app.winterButton, app.parulaButton, app.naviaButton, ...
                app.ADCBrushEditFieldLabel, app.XViewFieldLabel, app.YViewFieldLabel, app.ADCViewFieldLabel, ...
                app.ADCunitLabel1, app.ADCunitLabel2, app.ADCunitLabel3, app.M0MapSlicesEditFieldLabel, ...
                app.grayButton2, app.autumnButton2, app.winterButton2, app.parulaButton2, app.naviaButton2, ...
                app.M0BrushEditFieldLabel, app.grayButton3, app.autumnButton3, app.winterButton3, ...
                app.parulaButton3, app.naviaButton3, app.LogLabel, app.LinearLabel, app.ImageLabel, ...
                app.ADCMapLabel, app.M0MapLabel, app.R2MapLabel, ...
                app.EPIRampSamplingCheckBox, app.EPIPOCSCheckBox, ...
                app.EPIdistortionCheckBox, app.EPIregistrationCheckBox};

            for k = 1:numel(texts)
                texts{k}.FontColor = fontColor;
            end

            % Selection check boxes
            for k = 1:adcmapp.Constants.SELECTION_BOXES
                app.("CheckBox_" + k).FontColor = fontColor;
            end

            % Gauges
            app.RegProgressGauge.FontColor = gaugeColor;
            app.RegProgressGauge.BackgroundColor = gaugeBackgroundColor;
            app.FitProgressGauge.FontColor = gaugeColor;
            app.FitProgressGauge.BackgroundColor = gaugeBackgroundColor;

            % Images and figures
            axesList = {app.RawDataFig, app.ADCMapFig, app.M0MapFig, app.R2MapFig};
            for k = 1:numel(axesList)
                axesList{k}.Color = movieFigureColor;
                axesList{k}.XColor = movieFigureColor;
                axesList{k}.YColor = movieFigureColor;
            end

            plots = {app.ADClogPlot, app.ADClinearPlot};
            for k = 1:numel(plots)
                plots{k}.Color = figureColor;
                plots{k}.XColor = fontColor;
                plots{k}.YColor = fontColor;
            end

        end % SetAppColorsFcn


        % Check for a newer version
        function CheckVersionFnc(app)

            % Purpose:
            %   Tells the user when a newer release is published.
            %
            % How it works:
            %   Each release of the app lives in a repository of its own,
            %   ADCmapping-v<major.minor>, whose appversion.txt holds
            %   ADCmapp_version=x.y.z. The raw file is read, newest repository first,
            %   and the first that answers is compared with this version. Version 2.5
            %   read the github page of the file instead, which is HTML and not the file.
            %
            %   A repository that does not exist answers at once with an HTTP status, and
            %   the next one is worth trying. Any other failure -- no network, a timeout,
            %   a proxy refusing the connection -- would fail the same way for every
            %   repository, each after the full timeout, so the search stops there, as
            %   in T2mapp and Retrospective: five repositories at five seconds would stall
            %   the start by 25 s on a machine without a network.
            %
            %   Any failure is reported once and is not an error. Whether to look, and how
            %   long to wait, are settings.

            if ~app.jsonPar.checkForUpdates
                app.TextMessage('Version check switched off in the settings ...');
                return
            end

            repositories = ["ADCmapping-v3.3", "ADCmapping-v3.2", "ADCmapping-v3.1", "ADCmapping-v3.0", "ADCmapping-v2.5"];
            opts = weboptions('Timeout', app.jsonPar.versionCheckTimeout, 'ContentType', 'text', 'CharacterEncoding', 'UTF-8');
            tok = {};

            for repository = repositories
                try
                    webTxt = strtrim(string(webread("https://raw.githubusercontent.com/Moby1971/" + repository + "/main/appversion.txt", opts)));
                    tok = regexp(webTxt, 'ADCmapp_version\s*=\s*([0-9]+(?:\.[0-9]+){1,3})', 'tokens', 'once', 'ignorecase');
                catch webME
                    tok = {};
                    if ~contains(webME.identifier, 'StatusCodeError')
                        break
                    end
                end
                if ~isempty(tok)
                    break
                end
            end

            if isempty(tok)
                app.TextMessage("Could not check the ADC mapping app version ...");
                return
            end

            newVersion = sscanf(char(tok{1}), '%d.%d.%d').';
            current = sscanf(char(app.appVersion), '%d.%d.%d').';
            newVersion(end+1:3) = 0;
            current(end+1:3) = 0;

            app.TextMessage("Current version = " + app.appVersion);
            app.TextMessage("Newest version = " + string(tok{1}));

            difference = find(newVersion ~= current, 1);
            if ~isempty(difference) && newVersion(difference) > current(difference)
                app.TextMessage("A new version of the ADC mapping app is available ...");
                app.TextMessage("Visit https://github.com/Moby1971/ to update ...");
            else
                app.TextMessage("The ADC mapping app is up to date ...");
            end

        end % CheckVersionFnc


        % Switch fit parameters buttons on/off
        function SetFitButtons(app,code)

            % Purpose:
            %   Enables or disables the fit parameters and the image selection boxes.
            %
            % How it works:
            %   A selection box is only enabled for an image the series has.

            widgets = {app.FirstSliceEditField, app.FirstSliceEditFieldLabel, app.LastSliceEditField, ...
                app.LastSliceEditFieldLabel, app.Rsquare, app.RsquareLabel, app.Threshold, app.ThresholdLabel, ...
                app.ImportGradTableButton};
            app.SetEnableFcn(widgets, code ~= 0);

            for k = 1:adcmapp.Constants.SELECTION_BOXES
                app.SetEnableFcn({app.("CheckBox_" + k)}, code ~= 0 && k <= app.td.nr);
            end

            drawnow;

        end


        % Switch image selection buttons on/off
        function SetImageSelectionButtons(app,code)

            % Purpose:
            %   Enables or disables the image viewer's slice, image, window and level
            %   controls.

            widgets = {app.SlicesEditField, app.SlicesEditFieldLabel, app.SlicesSlider, app.ImageEditField, ...
                app.ImageEditFieldLabel, app.ImageSlider, app.WindowEditField, app.WindowEditFieldLabel, ...
                app.WindowSlider, app.LevelEditField, app.LevelEditFieldLabel, app.LevelSlider, app.AutoLWButton};
            app.SetEnableFcn(widgets, code ~= 0);

            drawnow;

        end


        % Switch image segmentation buttons on/off
        function SetSegmentationButtons(app,code)

            % Purpose:
            %   Enables or disables the threshold, the mask operations and their size, and
            %   Undo when there is an operation to undo.

            widgets = {app.ThresholdEditField, app.ThresholdEditFieldLabel, app.ThresholdSlider, ...
                app.ErodeButton, app.DilateButton, app.CloseButton, app.OpenButton, app.MaskSizeEditField};
            app.SetEnableFcn(widgets, code ~= 0);
            app.SetEnableFcn({app.UndoButton}, code ~= 0 && ~isempty(app.maskAfterOperation));

            drawnow;

        end


        % Map controls on/off
        function SetMapButtons(app,code)

            % Purpose:
            %   Enables or disables the map viewers: the slice controls, the ADC scale,
            %   the colour maps and the ROI tools.
            %
            % How it works:
            %   Maps can only be looked at once they have been fitted, so every step
            %   switches these off while it runs and on again when valid maps remain.
            %   The slice controls are only offered when there is more than one slice.

            on = code ~= 0;

            app.SetEnableFcn({app.ADCMapSlicesEditField, app.ADCMapSlicesEditFieldLabel, app.ADCMapSlicesSlider, ...
                app.M0MapSlicesEditField, app.M0MapSlicesEditFieldLabel, app.M0MapSlicesSlider}, on && app.td.ns > 1);

            app.SetEnableFcn({app.ADCScaleEditField, app.ADCScaleMaxLabel, app.ADCScaleMinEditField, ...
                app.ADCScaleMinLabel, app.ADCScaleAutoButton, ...
                app.grayButton, app.autumnButton, app.winterButton, app.parulaButton, app.naviaButton, ...
                app.grayButton2, app.autumnButton2, app.winterButton2, app.parulaButton2, app.naviaButton2, ...
                app.grayButton3, app.autumnButton3, app.winterButton3, app.parulaButton3, app.naviaButton3, ...
                app.ADCROIbutton, app.M0ROIbutton, app.ADCEraseButton, app.M0EraseButton, ...
                app.ADCBrushEditField, app.ADCBrushEditFieldLabel, app.M0BrushEditField, ...
                app.M0BrushEditFieldLabel, app.ADCexportRoiDataButton}, on);

            drawnow;

        end


        % Initialize the GUI
        function InitializeUI(app)

            % Purpose:
            %   Puts the interface in its starting state: colours, icons, sounds,
            %   plot settings, colour maps and the parameter fields.
            %
            % How it works:
            %   The registration and fit fields start from the settings, whose own
            %   defaults are the shipped parameter defaults in +adcmapp, so the values
            %   a script gets and the values the app starts with are the same numbers.

            app.TextMessage('Initializing UI ...');

            % App version
            app.Credits.Text = strcat("Gustav Strijkers, Amsterdam UMC, Version ",app.appVersion);

            % Define app colors
            app.brightred = 'Red';
            app.red = '#D95319';
            app.brightgreen = 'Green';
            app.green = '#6CB557';
            app.yellow = 'Yellow';
            app.orange = '#FDA400';
            app.blue = '#0073BE';
            app.lightblue = '#DEE6EB';
            app.grey = '#787878';
            app.guiBlack = [0.15,0.15,0.15];
            app.guiGrey = [0.94,0.94,0.94];
            app.guiMiddleGrey = [0.9,0.9,0.9];
            app.guiDarkGrey = [0.5,0.5,0.5];
            app.guiWhite = [1.0,1.0,1.0];
            app.guiBlackBlue = [0.02,0.13,0.3];

            % Logos
            app.TulpLogo.ImageSource = 'AmsterdamUMC.png';
            app.MRSImage.ImageSource = 'MRSolutionsLogo.gif';

            % Sounds
            [app.warningSound,~] = audioread('Glass.aiff');
            [app.errorSound,~] = audioread('Ping.aiff');

            % Icons
            app.LoadButton.Icon = 'disk.png';
            app.RegisterButton.Icon = 'sort.gif';
            app.MaskButton.Icon = 'threshold.gif';
            app.FitADCButton.Icon = 'exponential.gif';
            app.DCMNIFTIButton.Icon = 'disk.png';
            app.GIFButton.Icon = 'disk.png';
            app.ExitButton.Icon = 'exit.png';
            app.StatusImage.ImageSource = 'statusokay.gif';
            app.ADCImage.ImageSource = 'square.gif';
            app.RawImage.ImageSource = 'square.gif';
            app.M0Image.ImageSource = 'square.gif';
            app.R2Image.ImageSource = 'square.gif';

            % Lights and buttons
            app.SetLights([0,0,0,0,0,0]);
            app.SetButtons([0,0,0,0,0,0,1]);
            app.SetFitButtons(0);
            app.SetImageSelectionButtons(0);
            app.SetSegmentationButtons(0);
            app.SetMapButtons(0);
            app.AbortFitButton.Enable = 'off';
            app.AbortRegButton.Enable = 'off';

            % Plot properties
            plots = {app.RawDataFig, app.ADCMapFig, app.M0MapFig, app.R2MapFig, app.ADClogPlot, app.ADClinearPlot};
            for k = 1:numel(plots)
                plots{k}.Toolbar.Visible = 'off';
                plots{k}.Interactions = [];
            end

            % Colour maps, sounds and colour scheme, from the settings
            app.(app.jsonPar.display.adcColorMap + "Button").Value = 1;
            app.(app.jsonPar.display.m0ColorMap + "Button2").Value = 1;
            app.(app.jsonPar.display.r2ColorMap + "Button3").Value = 1;
            app.adccmap = app.ColorMapFromButtonsFcn(1, gray);
            app.m0cmap = app.ColorMapFromButtonsFcn(2, gray);
            app.r2cmap = app.ColorMapFromButtonsFcn(3, gray);
            app.AutoSoundsCheckBox.Value = logical(app.jsonPar.sounds);
            if strcmp(app.jsonPar.colorScheme, 'classic')
                app.ClassicButton.Value = true;
            else
                app.BlackWhiteButton.Value = true;
            end

            % Parameter fields from the settings
            app.Rsquare.Value = app.jsonPar.fitting.rSquare;
            app.Threshold.Value = logical(app.jsonPar.fitting.applyRsquare);
            app.RegistrationDropDown.Value = app.jsonPar.registration.method;
            app.EPIRampSamplingCheckBox.Value = logical(app.jsonPar.mrd.epiRampSampling);
            app.EPIPOCSCheckBox.Value = logical(app.jsonPar.mrd.epiPocs);
            app.EPIdistortionCheckBox.Value = logical(app.jsonPar.mrd.epiDistortionCorrection);
            app.EPIregistrationCheckBox.Value = logical(app.jsonPar.mrd.epiRegistration);
            app.ADCScaleEditField.Value = adcmapp.Constants.DEFAULT_ADC_SCALE;
            app.ADCScaleMinEditField.Value = 0;

            % ROIs and cross-hair
            app.roiFlag = false;
            app.roiPoints = [];

            app.SetStatus(3);

            drawnow;

        end


        %------------------------------------------------
        %
        %  Figures and Plots
        %
        %------------------------------------------------


        % Displays the mask in the raw image data
        function PlotImageFig(app)

            % Purpose:
            %   Draws the selected image of the series with the mask overlaid in red.
            %
            % How it works:
            %   Since one axes cannot hold two colour maps, the map is split in a grey
            %   half [0 1] for the image and a red half [1 2] for the mask overlay. The
            %   threshold fields are then set for the slice shown, on the reference
            %   image the mask is made from.

            if ~app.validDataFlag
                return
            end

            cla(app.RawDataFig);
            hold(app.RawDataFig,'on');

            cmap1 = [linspace(0,1,128)',linspace(0,1,128)',linspace(0,1,128)'];
            cmap2 = [linspace(0,1,128)', zeros(128,2)];
            cmap = [cmap1;cmap2];
            cmap(cmap > 1) = 1;

            slice = app.SlicesEditField.Value;
            image = app.ImageEditField.Value;
            im = reshape(app.td.images(image,slice,:,:), app.td.dimx, app.td.dimy);

            % Scale
            if app.AutoLWButton.Value == 1
                maxIm = max(im(:));
                if ~(maxIm > 0)
                    maxIm = 1;
                end
                app.WindowEditField.Value = maxIm;
                app.WindowSlider.Value = maxIm;
                app.LevelEditField.Value = maxIm/2;
                app.LevelSlider.Value = maxIm/2;
            end

            % Window and level
            im = (1/app.WindowEditField.Value)*(im - app.LevelEditField.Value + app.WindowEditField.Value/2);
            im(im<0) = 0;
            im(im>0.9999) = 0.9999;

            xlim(app.RawDataFig,[1, app.td.dimx]);
            ylim(app.RawDataFig,[1, app.td.dimy]);
            imshow(rot90(im),[0,2],'Colormap',cmap,'InitialMagnification','fit','Parent',app.RawDataFig);

            % Overlay with the mask
            mask = reshape(app.td.mask(slice,:,:), app.td.dimx, app.td.dimy);
            overlay = imshow(rot90(2 - mask),[0 2],'Colormap',cmap,'InitialMagnification','fit','Parent',app.RawDataFig);
            set(overlay,'AlphaData',rot90(0.7 - 0.7*mask));

            % Image aspect ratio
            daspect(app.RawDataFig,[app.td.aspectRatio 1 1]);

            hold(app.RawDataFig,'off');
            drawnow;

            % Threshold limits and the value for this slice
            reference = app.td.images(adcmapp.fit.referenceIndex(app.td),slice,:,:);
            top = max(reference(:)) + 1;
            app.ThresholdEditField.Limits = [0 top];
            app.ThresholdSlider.Limits = [0 top];
            thr = min(max(app.td.threshold(slice),0),top);
            app.ThresholdEditField.Value = thr;
            app.ThresholdSlider.Value = thr;

        end % PlotImageFig


        % Plots the maps for the current slice
        function PlotMaps(app)

            % Purpose:
            %   Draws the ADC, M0 and R-square maps of the selected slice.
            %
            % How it works:
            %   ADC is shown from the Min to the Max scale field, M0 up to the 99.5th percentile
            %   of the whole map, so a few outlying voxels do not set the scale and the
            %   slices stay comparable, and R-square from just below the cut-off to 1.
            %   A redraw replaces the images already on the axes. An ROI or cross-hair
            %   on the slice shown is drawn again.

            if ~app.validFitFlag
                return
            end

            slice = app.ADCMapSlicesEditField.Value;
            dimx = app.td.dimx;
            dimy = app.td.dimy;
            axesList = [app.ADCMapFig, app.M0MapFig, app.R2MapFig];

            for k = 1:numel(axesList)
                hold(axesList(k),'on');
            end

            % With hold on, imshow would otherwise stack a new copy at every redraw
            delete(findobj(axesList,'Type','image'));

            % ADC map
            im = reshape(app.td.adcmap(slice,:,:), dimx, dimy);
            scale = max(app.ADCScaleEditField.Value, adcmapp.Constants.MIN_ADC_SCALE);
            low = app.ADCScaleMinEditField.Value;
            if low >= scale
                low = 0;
            end
            xlim(app.ADCMapFig,[1, dimx]);
            ylim(app.ADCMapFig,[1, dimy]);
            imshow(rot90(im),[low scale],'Colormap',app.adccmap,'InitialMagnification','fit','Parent',app.ADCMapFig);
            daspect(app.ADCMapFig,[app.td.aspectRatio 1 1]);
            colorbar(app.ADCMapFig,'east','Ticks',[]);

            % M0 map
            im = reshape(app.td.m0map(slice,:,:), dimx, dimy);
            m0scale = adcmapp.util.robustDisplayMax(app.td.m0map, adcmapp.Constants.M0_DISPLAY_PERCENTILE);
            xlim(app.M0MapFig,[1, dimx]);
            ylim(app.M0MapFig,[1, dimy]);
            imshow(rot90(im),[0 m0scale],'Colormap',app.m0cmap,'InitialMagnification','fit','Parent',app.M0MapFig);
            daspect(app.M0MapFig,[app.td.aspectRatio 1 1]);

            % R-square map; the range cannot be empty, even at a cut-off of 1
            im = reshape(app.td.r2map(slice,:,:), dimx, dimy);
            low = min(app.Rsquare.Value, 0.98) - 0.01;
            xlim(app.R2MapFig,[1, dimx]);
            ylim(app.R2MapFig,[1, dimy]);
            imshow(rot90(im),[low 1],'Colormap',app.r2cmap,'InitialMagnification','fit','Parent',app.R2MapFig);
            daspect(app.R2MapFig,[app.td.aspectRatio 1 1]);
            colorbar(app.R2MapFig,'east','Ticks',[low, 1]);
            delete(app.txt1);
            delete(app.txt2);
            app.txt1 = text(app.R2MapFig,0.85,0.95,'1.0','Units','normalized','Color',app.grey,'FontSize',14);
            app.txt2 = text(app.R2MapFig,0.85,0.05,num2str(app.Rsquare.Value),'Units','normalized','Color',app.grey,'FontSize',14);

            % ROI or cross-hair, on the slice it was placed on
            if app.roiFlag && ~isempty(app.roiPoints)
                plotPoints = app.roiPoints(app.roiPoints(:,3)==slice, :);
                app.PlotPoint(plotPoints(:,1),plotPoints(:,2),dimy);
            elseif ~app.roiFlag && size(app.roiPoints,1) == 1
                if app.roiPoints(3) == slice
                    app.PlotCross(app.roiPoints(1),app.roiPoints(2),dimy);
                else
                    delete(app.crossMD);
                    delete(app.crossM0);
                end
            end

            for k = 1:numel(axesList)
                hold(axesList(k),'off');
            end

            drawnow;

        end % PlotMaps


        % Plots the fit results in the two fit windows
        function PlotFits(app)

            % Purpose:
            %   Draws the signal under the cross-hair or the ROI against b, with the
            %   fitted decay, on a linear and on a logarithmic scale.
            %
            % How it works:
            %   An ROI is averaged over its fitted pixels, or over all of them when
            %   none was fitted. The points of the images the fit leaves out are drawn
            %   in a second colour. When there is no fitted ADC under the cursor, the
            %   graphs stay empty and the ADC field shows 0.

            if ~app.validFitFlag || isempty(app.roiPoints)
                return
            end

            cla(app.ADClinearPlot);
            cla(app.ADClogPlot);
            hold(app.ADClinearPlot,'on');
            hold(app.ADClogPlot,'on');

            % The points are coloured by the selection the maps were fitted
            % with, which is what the curve drawn through them describes
            cmap = [adcmapp.util.hex2rgb(app.red) ; adcmapp.util.hex2rgb(app.blue)];
            colours = cmap(double(app.td.bSelection) + 1,:);

            if app.roiFlag

                % ROI average
                n = size(app.roiPoints,1);
                adc = zeros(n,1);
                m0 = zeros(n,1);
                series = zeros(app.td.nr,n);

                for i = 1:n
                    x = app.roiPoints(i,1);
                    y = app.roiPoints(i,2);
                    z = app.roiPoints(i,3);
                    adc(i) = app.td.adcmap(z,x,y);
                    m0(i) = app.td.m0map(z,x,y);
                    series(:,i) = app.td.images(:,z,x,y);
                end

                fitted = adc > 0;
                if any(fitted)
                    app.datapoints = mean(series(:,fitted),2);
                    adcValue = mean(adc(fitted));
                    m0Value = mean(m0(fitted));
                else
                    app.datapoints = mean(series,2);
                    adcValue = 0;
                    m0Value = 0;
                end

            else

                % Cross-hair
                x = app.roiPoints(1);
                y = app.roiPoints(2);
                z = app.roiPoints(3);
                adcValue = app.td.adcmap(z,x,y);
                m0Value = app.td.m0map(z,x,y);
                app.datapoints = app.td.images(:,z,x,y);

            end

            b = app.td.bValues;
            app.ADCViewField.Value = 0;

            if adcValue > 0 && m0Value > 0

                a = 0:1.1*max(b);
                s = m0Value*exp(-a*adcValue/adcmapp.Constants.ADC_UNIT_FACTOR);

                % Linear
                scatter(app.ADClinearPlot,b,app.datapoints,[],colours,'filled');
                plot(app.ADClinearPlot,a,s);
                xlim(app.ADClinearPlot,[-0.5, 1.1*max(b)]);
                ylim(app.ADClinearPlot,[-0.5, 1.1*max([s(:); app.datapoints(:)])]);

                % Logarithmic
                scatter(app.ADClogPlot,b,log(app.datapoints),[],colours,'filled');
                plot(app.ADClogPlot,a,log(s));
                xlim(app.ADClogPlot,[-0.5, 1.1*max(b)]);
                % Ordered, and never of zero span: [0.9*min, 1.01*max] turned upside
                % down where log(S) is negative (M0 below 1, a small decay, as on
                % background pixels fitted without a mask), ylim raised, and the plots
                % and the ADC field stayed those of the click before (found by the
                % Python port, A70)
                lo = min(log(s));
                hi = max(log(s));
                span = max(hi - lo, 0.1);
                ylim(app.ADClogPlot,[lo - 0.1*span, hi + 0.05*span]);

                app.ADCViewField.Value = adcValue;

            end

            hold(app.ADClinearPlot,'off');
            hold(app.ADClogPlot,'off');

        end % PlotFits


        % Draw a cross-hair on the ADC and M0 maps
        function PlotCross(app,xcor,ycor,ysize)

            if app.validFitFlag

                delete(app.crossMD);
                delete(app.crossM0);

                clr = 'r';
                if app.autumnButton.Value == 1 || app.naviaButton.Value == 1
                    clr = 'g';
                end

                app.crossMD = drawcrosshair(app.ADCMapFig, 'Position', [xcor ysize-ycor+1], 'Color', clr, 'LineWidth', 1);
                app.crossM0 = drawcrosshair(app.M0MapFig, 'Position', [xcor ysize-ycor+1], 'Color', 'r', 'LineWidth', 1);

            end

        end % PlotCross


        % Draw points on the ADC and M0 maps
        function PlotPoint(app,xcor,ycor,ysize)

            if app.validFitFlag

                clr = 'r';
                if app.autumnButton.Value == 1 || app.naviaButton.Value == 1
                    clr = 'g';
                end

                hold(app.ADCMapFig,'on');
                delete(app.roiMD);
                app.roiMD = plot(app.ADCMapFig, xcor, ysize-ycor+1, 's', 'MarkerSize', 4, 'Color', clr, 'MarkerFaceColor', clr);
                hold(app.ADCMapFig,'off');

                hold(app.M0MapFig,'on');
                delete(app.roiM0);
                app.roiM0 = plot(app.M0MapFig, xcor, ysize-ycor+1, 's', 'MarkerSize', 4, 'Color', 'r', 'MarkerFaceColor', clr);
                hold(app.M0MapFig,'off');

            end

        end % PlotPoint


        %------------------------------------------------
        %
        %  Parameters and settings
        %
        %------------------------------------------------


        % Copy the acquisition outputs to the fields that display them
        function MirrorDataOutputsFcn(app)

            % Purpose:
            %   Puts every acquisition value the app shows into its display field,
            %   in one place.
            %
            % How it works:
            %   These fields are read-only mirrors of the dataset: nothing below the
            %   app writes them, and nothing reads them back for a decision.
            %   tests/tMirrors checks that the import sets everything shown here. The
            %   aspect ratio field only takes 0.25 to 4, so the ratio is clamped to it.

            % What the dataset was read from: its DICOM folder, or the MRD file of a scan
            % not exported, whose series is in a private temporary folder until the
            % export (adcData.dataSource; found by the Python port, A102)
            app.DicomDirViewField.Value = char(app.td.dataSource);
            app.TRViewField.Value = app.td.tr;
            app.TEViewField.Value = app.td.te;
            app.NRImagesViewField.Value = app.td.nr;
            app.dimxViewField.Value = app.td.dimx;
            app.dimyViewField.Value = app.td.dimy;
            app.SlicesViewField.Value = app.td.ns;
            app.SLTViewField.Value = app.td.sl;
            app.FOVViewField.Value = app.td.fov;
            app.AspectRatioViewField.Value = min(max(app.td.fovRatio, 0.25), 4);

        end % MirrorDataOutputsFcn


        % Fit the slice and image widgets to the dataset
        function SetWidgetLimitsFcn(app)

            % Purpose:
            %   Sets the ranges of every slice and image control to the size of the
            %   series.

            sliceWidgets = {app.SlicesEditField, app.SlicesSlider, app.ADCMapSlicesEditField, ...
                app.ADCMapSlicesSlider, app.M0MapSlicesEditField, app.M0MapSlicesSlider, ...
                app.FirstSliceEditField, app.LastSliceEditField};

            app.SetRangeFcn(sliceWidgets, app.td.ns);
            app.SetRangeFcn({app.ImageEditField, app.ImageSlider}, app.td.nr);

        end % SetWidgetLimitsFcn


        % The range of a set of widgets
        function SetRangeFcn(app, widgets, n) %#ok<INUSL>

            % Purpose:
            %   Sets a set of edit fields and sliders to run from 1 to n.
            %
            % How it works:
            %   The value is put at 1 first, so that it is never outside the new range.
            %   A slider needs two different ends, so a range of one runs to 2 there.

            for k = 1:numel(widgets)
                widgets{k}.Value = 1;
                if isa(widgets{k}, 'matlab.ui.control.Slider')
                    widgets{k}.Limits = [1, max(n, 2)];
                else
                    widgets{k}.Limits = [1, max(n, 1)];
                end
            end

        end % SetRangeFcn


        % Clear what belongs to the previous dataset
        function ResetForNewDataFcn(app)

            % Purpose:
            %   Clears the plots, the ROI and the selection so that nothing from the
            %   last dataset survives into this one.
            %
            % How it works:
            %   The maps, the mask and the thresholds were already reallocated by the
            %   import. Every image is selected for the fit, and the fit covers all
            %   slices.

            % Window and level
            app.WindowEditField.Value = adcmapp.Constants.DISPLAY_MAX;
            app.LevelEditField.Value = adcmapp.Constants.DISPLAY_LEVEL;
            app.WindowSlider.Value = adcmapp.Constants.DISPLAY_MAX;
            app.LevelSlider.Value = adcmapp.Constants.DISPLAY_LEVEL;
            app.AutoLWButton.Value = 1;

            % Segmentation: nothing of the last dataset's mask to undo
            app.maskBeforeOperation = [];
            app.maskAfterOperation = [];

            % ROI and plots
            app.roiPoints = [];
            app.datapoints = [];
            delete(app.roiMD);
            delete(app.roiM0);
            delete(app.crossMD);
            delete(app.crossM0);
            cla(app.RawDataFig);
            cla(app.ADCMapFig);
            cla(app.M0MapFig);
            cla(app.R2MapFig);
            cla(app.ADClogPlot);
            cla(app.ADClinearPlot);
            app.ADCViewField.Value = 0;
            app.ADCScaleEditField.Value = adcmapp.Constants.DEFAULT_ADC_SCALE;
            app.ADCScaleMinEditField.Value = 0;

            % The fit range and the selection
            app.FirstSliceEditField.Value = 1;
            app.LastSliceEditField.Value = app.td.ns;
            app.SetDefaultSelectionFcn;

        end % ResetForNewDataFcn


        % Read the image selection boxes into the dataset
        function MakeSelectionFcn(app)

            % Purpose:
            %   Tells the dataset which images of the series the user wants the fit
            %   to use.
            %
            % How it works:
            %   There are Constants.SELECTION_BOXES boxes; images beyond them are
            %   always used.

            selection = true(app.td.nr,1);

            for k = 1:min(adcmapp.Constants.SELECTION_BOXES, app.td.nr)
                selection(k) = logical(app.("CheckBox_" + k).Value);
            end

            app.td.bSelection = selection;

        end % MakeSelectionFcn


        % Select every image
        function SetDefaultSelectionFcn(app)

            % Purpose:
            %   Ticks every image. An image is left out of the fit by unticking it in
            %   the fit parameters.

            for k = 1:adcmapp.Constants.SELECTION_BOXES
                app.("CheckBox_" + k).Value = 1;
            end

            app.MakeSelectionFcn;

        end % SetDefaultSelectionFcn


        % Show the b-value and gradient direction of the image in view
        function ShowImageInfoFcn(app)

            % Purpose:
            %   Fills the b-value and gradient fields under the image viewer for the
            %   image selected there.

            image = app.ImageEditField.Value;
            app.BvalueViewField.Value = num2str(app.td.bValues(image));
            app.GradViewField.Value = num2str(app.td.gradients(image,:));

        end % ShowImageInfoFcn


        % Change the map slice
        function ChangeMapSlices(app, value)

            % Purpose:
            %   Moves the ADC and the M0 map viewers to the same slice.

            value = min(max(1, round(value)), max(1, app.td.ns));
            app.ADCMapSlicesEditField.Value = value;
            app.ADCMapSlicesSlider.Value = value;
            app.M0MapSlicesEditField.Value = value;
            app.M0MapSlicesSlider.Value = value;

        end % ChangeMapSlices


        % An ADC scale that shows the map well
        function AutoScaleFcn(app)

            % Purpose:
            %   Sets the top of the ADC scale to a multiple of the mean fitted ADC of
            %   the maps.
            %
            % How it works:
            %   Constants.ADC_AUTO_SCALE_FACTOR times the mean of the fitted pixels,
            %   rounded to one decimal and never below Constants.MIN_ADC_SCALE; the
            %   default scale when nothing was fitted. Version 2.5 rounded to whole
            %   numbers, which put a map of low ADCs at a scale of 0. The bottom of the
            %   scale goes back to 0.

            values = nonzeros(app.td.adcmap);
            scale = round(adcmapp.Constants.ADC_AUTO_SCALE_FACTOR*mean(values), 1);

            if isempty(values) || ~isfinite(scale)
                scale = adcmapp.Constants.DEFAULT_ADC_SCALE;
            end

            app.ADCScaleMinEditField.Value = 0;
            app.ADCScaleEditField.Value = max(scale, adcmapp.Constants.MIN_ADC_SCALE);

        end % AutoScaleFcn


        % Fit the selected slices
        function FitSlicesFcn(app, fitParams)

            % Purpose:
            %   Fits every pixel of the slices between the first and the last slice
            %   field.
            %
            % How it works:
            %   One slice at a time, so that the maps are drawn as they fill in and the
            %   abort button is honoured between slices. The maps of the other slices
            %   are left as they were.

            reporter = AppReporter(app,'fit');
            slices = app.FirstSliceEditField.Value:app.LastSliceEditField.Value;
            reporter.setTotal(numel(slices));
            reporter.setAborted(false);

            for slice = slices

                if reporter.aborted()
                    break
                end


                [app.td.m0map(slice,:,:), app.td.adcmap(slice,:,:), app.td.r2map(slice,:,:), counts] = adcmapp.fit.fitSlice( ...
                    reshape(app.td.images(:,slice,:,:), app.td.nr, app.td.dimx, app.td.dimy), ...
                    reshape(app.td.mask(slice,:,:), app.td.dimx, app.td.dimy), ...
                    app.td.bValues, app.td.bSelection, fitParams);

                told = sprintf("Slice %d: %d of %d pixels fitted, %d without signal, %d not a diffusion coefficient", ...
                    slice, counts.fitted, counts.offered, counts.noSignal, counts.notADiffusionCoefficient);
                if fitParams.applyRsquare
                    told = told + sprintf(", %d below R-square %.2f", counts.belowRsquare, fitParams.rSquare);
                end
                app.TextMessage(told + " ...");

                app.ChangeMapSlices(slice);
                app.AutoScaleFcn;
                app.validFitFlag = true;
                app.PlotMaps;
                app.validFitFlag = false;

                reporter.advance();

            end

            reporter.finish();

        end % FitSlicesFcn


        % Change the mask by a morphological operation
        function MaskOperationFcn(app, operation)

            % Purpose:
            %   Erodes, dilates, closes or opens the masked region, the one drawn in red,
            %   in every slice.
            %
            % How it works:
            %   The mask is 1 where a pixel is fitted and the red overlay shows where it
            %   is 0, so the operation is applied to the background, 1 - mask, as in
            %   version 2.5: Erode shrinks the red region, Dilate grows it, Close fills
            %   gaps in it and Open removes small red specks. The element comes from
            %   adcmapp.util.maskElement: round in millimetres, with the size field as its
            %   radius in the finest pixels of adcmapp.util.pixelSize. Square pixels give
            %   the exact disk used before, size 1 being the 3 x 3 cross.
            %
            %   The mask before and after is kept for Undo, as logical arrays. A failure
            %   halfway puts the mask back as it was.

            if ~app.validDataFlag
                return
            end

            previousMask = app.td.mask;

            try

                pixelSizes = adcmapp.util.pixelSize(app.td);
                element = adcmapp.util.maskElement(app.MaskSizeEditField.Value, pixelSizes, false);

                for slice = 1:app.td.ns
                    background = 1 - reshape(app.td.mask(slice,:,:), app.td.dimx, app.td.dimy);
                    background = app.MorphologyFcn(background, operation, element);
                    app.td.mask(slice,:,:) = reshape(1 - background, 1, app.td.dimx, app.td.dimy);
                end

                app.maskBeforeOperation = previousMask == 1;
                app.maskAfterOperation = app.td.mask == 1;
                app.UndoButton.Enable = 'on';
                app.PlotImageFig;

            catch ME

                app.td.mask = previousMask;
                app.TextMessage(ME.message);
                app.TextMessage('ERROR: the mask could not be changed ...');
                app.SetStatus(2);

            end

        end % MaskOperationFcn


        % One morphological operation on a background image or volume
        function background = MorphologyFcn(app, background, operation, element) %#ok<INUSL>

            % Purpose:
            %   Erodes, dilates, closes or opens the background with the element.

            switch operation
                case "erode"
                    background = imerode(background, element);
                case "dilate"
                    background = imdilate(background, element);
                case "close"
                    background = imclose(background, element);
                case "open"
                    background = imopen(background, element);
            end

        end % MorphologyFcn


        % Whether files can be written in a folder
        function writable = FolderWritableFcn(app, folder) %#ok<INUSL>

            % Purpose:
            %   Tells whether an export can go into a folder, before the export starts.
            %
            % How it works:
            %   A folder is made there and removed again, which is the question that
            %   matters; permissions, quotas and read-only mounts all answer it.

            writable = false;

            if ~isfolder(folder)
                return
            end

            probe = fullfile(folder, ['adcmapp-write-test-' char(java.util.UUID.randomUUID)]);
            [made, ~] = mkdir(probe);

            if made
                rmdir(probe);
                writable = true;
            end

        end % FolderWritableFcn


        % Write the animations, and photograph the window beside them
        function ExportGifsFcn(app, baseFolder)

            % Purpose:
            %   Makes the numbered output folder for this export, puts a picture of
            %   the app's window in it, and writes the animations.
            %
            % How it works:
            %   The photographs are why this lives in the app: switching tabs and
            %   calling exportapp is GUI work. exportGifADC is given the finished folder.

            gifFolder = adcmapp.io.numberedFolder(fullfile(baseFolder, 'GIF'), [char(app.td.tag) 'ADC']);
            app.TextMessage(strcat("GIF export folder = ", gifFolder, " ..."));

            app.TabGroup2.SelectedTab = app.RawImagesTab;
            app.TabGroup3.SelectedTab = app.ADCmapTab;
            exportapp(app.ADCmappUIFigure, fullfile(gifFolder, strcat("ADCmap", app.td.tag, ".png")));
            app.TabGroup3.SelectedTab = app.M0R2Tab;
            exportapp(app.ADCmappUIFigure, fullfile(gifFolder, strcat("M0map", app.td.tag, ".png")));
            app.TabGroup3.SelectedTab = app.ADCmapTab;

            adcmapp.io.exportGifADC(app.td, gatherFitParams(app), gatherDisplayParams(app), gifFolder);

        end % ExportGifsFcn


        % Write a parameter set to the log
        function LogParamsFcn(app, label, params)

            % Purpose:
            %   Records exactly which parameters a registration or a fit ran with, so
            %   that a result can be traced back to what produced it.
            %
            % How it works:
            %   One line of JSON, to the log only; the message pane is not the place
            %   for it.

            try
                if app.logFile > 2
                    fprintf(app.logFile, '%s  %s parameters: %s\n', char(datetime('now','Format','HH:mm:ss')), ...
                        label, jsonencode(params));
                end
            catch
            end

        end % LogParamsFcn


        % Shut the app down
        function CloseAppFcn(app)

            % Purpose:
            %   Shut the app down, whether the exit button or the window's close box was
            %   used, also while the app is still busy, and leave the MATLAB session as it
            %   was found.
            %
            % How it works:
            %   While app code is still running -- the startup, a step or a loop -- deleting
            %   the app would make that code fail on a deleted object. The close is then
            %   deferred: the window is hidden, what runs is asked to stop, and a timer
            %   finishes the close once no app code runs any more (DeferredCloseFcn).
            %
            %   Otherwise each step is guarded on its own, so a failure in one cannot leave
            %   the window open or the log unclosed: the log is closed, and the path, the
            %   folder and the warning state MATLAB had are put back.
            %
            %   The figure is deleted before the app. Deleting only the app from the close
            %   box left the figure registered as the running app, and the app could then
            %   not be started again. Both go before the app's folders leave the search
            %   path: deleting the figure runs the app's destructor, which MATLAB cannot
            %   find once they are gone. What the restoring needs is kept first.
            %
            % Inputs:
            %   app - the running app
            %
            % Output:
            %   none - the app is closed, or its close is scheduled

            % Still busy: stop the fit or registration and close once it has finished
            if app.AppCodeRunningFcn

                if ~app.closeRequested
                    app.closeRequested = true;
                    app.abortFitFlag = true;
                    app.abortRegFlag = true;
                    app.TextMessage('Closing when the current step has finished ...');
                    app.ADCmappUIFigure.Visible = 'off';
                    % No limit on the ticks: 7200 of them, an hour, and a close asked for
                    % during a longer step never came (as P2ROUD6 status.md 115; found by
                    % the Python port, A13)
                    app.closeTimer = timer('Name', 'ADCmappCloseTimer', ...
                        'ExecutionMode', 'fixedSpacing', 'Period', 0.5, 'StartDelay', 0.5, ...
                        'TimerFcn', @(t,~) isvalid(app) && app.DeferredCloseFcn(t));
                    start(app.closeTimer);
                end

                return

            end

            % Close the log file
            try
                app.TextMessage('Closing ADCmapp ...');
                if app.logFile > 2
                    fclose(app.logFile);
                end
                app.logFile = -1;
            catch
            end

            % The temporary folder of a scan read from MRD and not exported
            try
                app.td.discardMrdWork();
            catch
            end

            % Keep what the restoring needs; the app is deleted before it is used
            appRoot = app.appFolder;
            startFolder = app.oldFolder;
            startWarnings = app.oldWarningState;

            % Delete the figure, then the app, while the app folders are still on the
            % search path: deleting the figure runs the app's destructor
            try
                delete(app.ADCmappUIFigure);
            catch
            end
            try
                delete(app);
            catch
            end

            % Remove the app folders from the search path, and return to the folder MATLAB
            % was in
            if ~isdeployed
                try
                    rmpath(genpath(appRoot));
                    cd(startFolder);
                catch
                end
            end

            % Restore the warning state MATLAB had
            try
                if ~isempty(startWarnings)
                    warning(startWarnings);
                end
            catch
            end

        end % CloseAppFcn


        % Finish a close that had to wait
        function done = DeferredCloseFcn(app, t)

            % Purpose:
            %   Complete a close that was requested while app code was still running.
            %
            % How it works:
            %   The close timer calls this twice a second. While app code still runs
            %   nothing else happens. Once it has stopped, one more tick is waited, so
            %   that the callback that ended the step has returned as well, and the app is
            %   then closed the normal way.
            %
            % Inputs:
            %   app - the app being closed
            %   t   - the close timer
            %
            % Output:
            %   done - always true, since the timer's function handle needs a value

            done = true;

            try

                % Keep the window hidden; a dialog that returned may have raised it
                app.ADCmappUIFigure.Visible = 'off';

                % Still running: wait for the next tick
                if app.AppCodeRunningFcn
                    t.UserData = 0;
                    return
                end

                % Idle for the first time: wait one more tick
                if isempty(t.UserData) || t.UserData < 1
                    t.UserData = 1;
                    return
                end

                % Idle twice in a row: close
                stop(t);
                delete(t);
                app.closeTimer = [];
                app.CloseAppFcn;

            catch

                % A timer that fails is removed rather than left firing
                try
                    stop(t);
                    delete(t);
                catch
                end

            end

        end % DeferredCloseFcn


        % Check whether app code is running
        function running = AppCodeRunningFcn(app)

            % Purpose:
            %   Tell whether closing the app now would break code that is still running.
            %
            % How it works:
            %   Any method of the app still on the call stack -- a callback, the startup,
            %   a loop -- is running underneath the close request, or underneath the timer
            %   that asks. The closing methods themselves do not count, and neither do
            %   anonymous functions, whose names carry the method that created them.
            %
            %   Where the stack cannot be read, the BUSY status that every step sets is
            %   used instead.
            %
            % Inputs:
            %   app - the running app
            %
            % Output:
            %   running - true while app code other than the closing methods runs

            running = app.busyFlag;

            % The methods that do the closing
            closing = ["CloseAppFcn", "DeferredCloseFcn", "AppCodeRunningFcn", "ExitButtonPushed"];

            % The app's own methods on the call stack
            try
                names = string({dbstack.name});
                mine = startsWith(names, "ADCmapp.");
                if any(mine)
                    running = any(mine & ~contains(names, "@(") & ~contains(names, closing));
                end
            catch
            end

        end % AppCodeRunningFcn


        % Which colour map one panel's radio buttons are set to
        function cmap = ColorMapFromButtonsFcn(app, n, cmap)

            % Purpose:
            %   Reads the five colour map radio buttons of one panel and gives back the
            %   map they name.
            %
            % How it works:
            %   Panel 1 is ADC, whose buttons carry no number; 2 is M0 and 3 is R-square.
            %   The first entry is forced to black so the background of a map stays
            %   black whatever the colour map does at its bottom end.

            suffix = "";
            if n > 1
                suffix = string(n);
            end

            if app.("grayButton" + suffix).Value == 1
                cmap = gray;
            end

            if app.("autumnButton" + suffix).Value == 1
                cmap = autumn;
            end

            if app.("winterButton" + suffix).Value == 1
                cmap = winter;
            end

            if app.("parulaButton" + suffix).Value == 1
                cmap = parula;
            end

            if app.("naviaButton" + suffix).Value == 1
                cmap = adcmapp.util.naviaColorMap();
            end

            cmap(1,:) = [0 0 0];

        end % ColorMapFromButtonsFcn


    end % methods


    % ----------------------------------------------
    %
    % Public functions, available outside the app
    %
    % ----------------------------------------------


    methods (Access = public)


        % Text messages in the app
        function TextMessage(app, message)

            % Every message goes to the log as well, with the time of day, in its
            % own guard so that it reaches the file even when the pane cannot take
            % it, as while the interface is being torn down
            try
                if app.logFile > 2
                    fprintf(app.logFile, '%s  %s\n', char(datetime('now','Format','HH:mm:ss')), ...
                        char(strjoin(string(message), ' ')));
                end
            catch
            end

            try
                app.MessageBox.Value = [string(message) ; string(app.MessageBox.Value)];
                app.MessageBox.Value = app.MessageBox.Value(1:min(50,end),1);
                drawnow;
            catch
            end

        end % TextMessage


        % Status icon
        function SetStatus(app,status)

            % BUSY marks a running step until OK or ERROR ends it; a WARNING leaves the
            % flag as it was. A close request waits for the flag to clear
            if status == 3
                app.busyFlag = true;
            elseif status == 0 || status == 2
                app.busyFlag = false;
            end

            switch status

                case 0
                    app.StatusImage.ImageSource = 'statusokay.gif';
                    app.StatusLabel.FontColor = app.green;
                    app.StatusLabel.FontWeight = 'bold';
                    app.StatusLabel.FontSize = 14;
                    app.StatusLabel.Text = 'OK';

                case 1
                    app.StatusImage.ImageSource = 'statuswarning.gif';
                    app.StatusLabel.FontColor = app.orange;
                    if app.AutoSoundsCheckBox.Value
                        sound(app.warningSound,48000);
                    end
                    app.StatusLabel.FontWeight = 'bold';
                    app.StatusLabel.FontSize = 11;
                    app.StatusLabel.Text = 'WARNING';

                case 2
                    app.StatusImage.ImageSource = 'statuserror.gif';
                    app.StatusLabel.FontColor = app.brightred;
                    if app.AutoSoundsCheckBox.Value
                        sound(app.errorSound,48000);
                    end
                    app.StatusLabel.FontWeight = 'bold';
                    app.StatusLabel.FontSize = 12;
                    app.StatusLabel.Text = 'ERROR';

                case 3
                    app.StatusImage.ImageSource = 'busy.gif';
                    app.StatusLabel.FontColor = app.blue;
                    app.StatusLabel.FontWeight = 'bold';
                    app.StatusLabel.FontSize = 12;
                    app.StatusLabel.Text = 'BUSY';

            end

            drawnow;

        end


        % Load a DICOM series
        function ok = LoadDataset(app, folder)

            % Purpose:
            %   Loads a folder of diffusion-weighted DICOM files, or refuses it and
            %   says why.
            %
            % How it works:
            %   The work the load button does after the folder dialog, public so that
            %   a script or a test can drive the app without one. A refused series
            %   leaves the dataset as it was, and the reason is shown in the message
            %   pane.
            %
            % Inputs:
            %   folder - the folder holding the .dcm files of one series
            %
            % Outputs:
            %   ok - true when the series was loaded

            ok = false;

            % What a refused series leaves as it was: the dataset held, its flags and
            % lamps (the import does not touch adcData when it refuses; found by the
            % Python port, A3)
            previous.flags = [app.validDataFlag, app.validRegFlag, app.validFitFlag];
            previous.lamps = {app.Lamp1.Color, app.Lamp2.Color, app.Lamp3.Color, app.Lamp4.Color, ...
                app.Lamp5.Color, app.Lamp6.Color};
            refused = false;

            app.SetLights([3,0,0,0,0,0]);
            app.SetButtons([0,0,0,0,0,0,0]);
            app.SetFitButtons(0);
            app.SetImageSelectionButtons(0);
            app.SetSegmentationButtons(0);
            app.SetMapButtons(0);
            app.SetStatus(3);

            try

                app.TextMessage(strcat("Loading ", folder, " ..."));
                [valid, reason] = app.td.importDicom(folder, AppReporter(app));

                if ~valid
                    refused = true;
                    ME = MException('ADCmapp:InvalidData', '%s', "ERROR: " + reason + " ...");
                    throw(ME);
                end

                % A new dataset: whatever was registered or fitted is gone. Cleared here,
                % after the import: cleared before it, a refused series (or a stopped
                % reconstruction) stranded the dataset still held, its images on screen
                % and nothing but Load and Exit working (A3)
                app.validDataFlag = false;
                app.validRegFlag = false;
                app.validFitFlag = false;

                % Denoised as the series comes in, before anything registers it, only when
                % the settings file asks for it (denoising.dicom, with denoising.pca not
                % false): a DICOM series is magnitude only, and denoising it lowers the ADC.
                % A scan read from its MRD files is denoised by its reconstruction instead
                if app.jsonPar.denoising.pca && app.jsonPar.denoising.dicom
                    app.td.denoiseImages(AppReporter(app));
                end

                app.importFolder = char(folder);
                app.MirrorDataOutputsFcn;
                app.SetWidgetLimitsFcn;
                app.ResetForNewDataFcn;
                app.validDataFlag = true;
                app.TextMessage(sprintf("Dataset: %d images, %d slices, %d x %d, b-values %s ...", ...
                    app.td.nr, app.td.ns, app.td.dimx, app.td.dimy, mat2str(unique(app.td.bValues)')));

                % The middle slice, at the reference image
                slice = max(1, round(app.td.ns/2));
                image = adcmapp.fit.referenceIndex(app.td);
                app.SlicesEditField.Value = slice;
                app.SlicesSlider.Value = slice;
                app.ImageEditField.Value = image;
                app.ImageSlider.Value = image;
                app.ShowImageInfoFcn;
                app.PlotImageFig;

                app.SetFitButtons(1);
                app.SetImageSelectionButtons(1);
                app.SetSegmentationButtons(1);
                app.SetLights([1,0,0,0,0,0]);
                app.SetButtons([1,1,1,1,0,0,1]);
                app.SetStatus(0);
                ok = true;

            catch ME

                app.TextMessage(ME.message);
                if ~startsWith(string(ME.message), "ERROR")
                    app.TextMessage('ERROR: Something went wrong while loading the data ...');
                end
                if refused && previous.flags(1)
                    % The dataset held is untouched: usable as it was
                    app.validDataFlag = previous.flags(1);
                    app.validRegFlag = previous.flags(2);
                    app.validFitFlag = previous.flags(3);
                    for k = 1:numel(previous.lamps)
                        app.("Lamp" + k).Color = previous.lamps{k};
                    end
                    fitted = double(app.validFitFlag);
                    app.SetFitButtons(1);
                    app.SetImageSelectionButtons(1);
                    app.SetSegmentationButtons(1);
                    app.SetMapButtons(fitted);
                    app.SetButtons([1,1,1,1,fitted,fitted,1]);
                else
                    app.validDataFlag = false;
                    app.validRegFlag = false;
                    app.validFitFlag = false;
                    app.SetFitButtons(0);
                    app.SetLights([0,0,0,0,0,0]);
                    app.SetButtons([1,0,0,0,0,0,1]);
                end
                app.SetStatus(2);

            end

        end % LoadDataset


        % Reconstruct and load an MR Solutions EPI scan
        function ok = LoadMrdDataset(app, mrdFile, exportFolder)

            % Purpose:
            %   Reconstructs a diffusion-weighted EPI scan from its MRD files and
            %   loads it, writing nothing beside the scan (adcData.reconstructMrd;
            %   the export button writes the reconstruction, 2026-09-30); or refuses
            %   the scan and says why. Given an export folder, a script or a test gets
            %   the export there at once (adcData.importMrd), as before.
            %
            % How it works:
            %   The work the MRD load button does after the file dialog, public so that
            %   a script or a test can drive the app without one. adcData.importMrd is
            %   the whole route and ends by reading the series it wrote, so nothing here
            %   loads it a second time; from that point on this does what LoadDataset
            %   does after a DICOM load. A refused scan leaves the dataset as it was,
            %   and the reason is shown in the message pane.
            %
            %   A reconstruction takes minutes, so it reports through the
            %   'reconstruction' task, which is the registration's gauge, time field
            %   and abort flag: the Abort button beside them stops it, and so does the
            %   close box. A stopped run comes back as a refusal, since nothing was
            %   loaded.
            %
            % Inputs:
            %   mrdFile      - one MRD file of the scan
            %   exportFolder - where the reconstruction is written, as <exportFolder>/DICOM
            %                  and /Nifti; omitted or empty means the study folder the
            %                  scanner keeps the scan in
            %
            % Outputs:
            %   ok - true when the scan was reconstructed and loaded

            if nargin < 3
                exportFolder = '';
            end

            ok = false;

            % What a refused series leaves as it was: the dataset held, its flags and
            % lamps (the import does not touch adcData when it refuses; found by the
            % Python port, A3)
            previous.flags = [app.validDataFlag, app.validRegFlag, app.validFitFlag];
            previous.lamps = {app.Lamp1.Color, app.Lamp2.Color, app.Lamp3.Color, app.Lamp4.Color, ...
                app.Lamp5.Color, app.Lamp6.Color};
            refused = false;

            app.SetLights([3,0,0,0,0,0]);
            app.SetButtons([0,0,0,0,0,0,0]);
            app.SetFitButtons(0);
            app.SetImageSelectionButtons(0);
            app.SetSegmentationButtons(0);
            app.SetMapButtons(0);
            app.SetStatus(3);
            app.abortRegFlag = false;
            app.AbortRegButton.Enable = 'on';

            try

                app.TextMessage(strcat("Reconstructing ", mrdFile, " ..."));
                mrdParams = gatherMrdParams(app);
                app.LogParamsFcn('reconstruction', mrdParams);
                niftiFolder = '';
                [valid, reason] = app.td.reconstructMrd(mrdFile, mrdParams, ...
                    app.appVersion, AppReporter(app,'reconstruction'));

                % Given an export folder, the export follows at once. The dataset holds
                % the scan by then, so a failed export is said and the scan stays
                % loaded, to be exported again from the export button; it was taken
                % for a refused scan, and the window then showed the dataset before it
                % (found by the Python port, A100)
                if valid && ~isempty(exportFolder)
                    try
                        [~, niftiFolder] = app.td.exportMrd(exportFolder, AppReporter(app));
                    catch ME
                        if ME.identifier ~= "ADCmapp:ExportFailed"
                            rethrow(ME);
                        end
                        app.TextMessage(ME.message);
                        app.TextMessage('ERROR: the reconstruction could not be exported; it stays loaded, and the export button tries again ...');
                        niftiFolder = 'failed';
                    end
                end

                if ~valid
                    refused = true;
                    ME = MException('ADCmapp:InvalidData', '%s', "ERROR: " + reason + " ...");
                    throw(ME);
                end

                % A new dataset: whatever was registered or fitted is gone. Cleared here,
                % after the import: cleared before it, a refused series (or a stopped
                % reconstruction) stranded the dataset still held, its images on screen
                % and nothing but Load and Exit working (A3)
                app.validDataFlag = false;
                app.validRegFlag = false;
                app.validFitFlag = false;

                % The reconstruction has already named both export folders in the
                % message pane, so only a missing NIfTI is worth a line here
                if ~isempty(exportFolder) && isempty(niftiFolder)
                    app.TextMessage('WARNING: no NIfTI was written for this scan ...');
                end

                app.importFolder = char(fileparts(mrdFile));
                app.MirrorDataOutputsFcn;
                app.SetWidgetLimitsFcn;
                app.ResetForNewDataFcn;
                app.validDataFlag = true;
                app.TextMessage(sprintf("Dataset: %d images, %d slices, %d x %d, b-values %s ...", ...
                    app.td.nr, app.td.ns, app.td.dimx, app.td.dimy, mat2str(unique(app.td.bValues)')));

                % The middle slice, at the reference image
                slice = max(1, round(app.td.ns/2));
                image = adcmapp.fit.referenceIndex(app.td);
                app.SlicesEditField.Value = slice;
                app.SlicesSlider.Value = slice;
                app.ImageEditField.Value = image;
                app.ImageSlider.Value = image;
                app.ShowImageInfoFcn;
                app.PlotImageFig;

                app.SetFitButtons(1);
                app.SetImageSelectionButtons(1);
                app.SetSegmentationButtons(1);
                app.AbortRegButton.Enable = 'off';
                app.SetLights([1,0,0,0,0,0]);
                app.SetButtons([1,1,1,1,0,0,1]);
                app.SetStatus(0);
                ok = true;

            catch ME

                app.TextMessage(ME.message);
                if ~startsWith(string(ME.message), "ERROR")
                    app.TextMessage('ERROR: Something went wrong while reconstructing the scan ...');
                end
                app.AbortRegButton.Enable = 'off';
                if refused && previous.flags(1)
                    % The dataset held is untouched: usable as it was
                    app.validDataFlag = previous.flags(1);
                    app.validRegFlag = previous.flags(2);
                    app.validFitFlag = previous.flags(3);
                    for k = 1:numel(previous.lamps)
                        app.("Lamp" + k).Color = previous.lamps{k};
                    end
                    fitted = double(app.validFitFlag);
                    app.SetFitButtons(1);
                    app.SetImageSelectionButtons(1);
                    app.SetSegmentationButtons(1);
                    app.SetMapButtons(fitted);
                    app.SetButtons([1,1,1,1,fitted,fitted,1]);
                else
                    app.validDataFlag = false;
                    app.validRegFlag = false;
                    app.validFitFlag = false;
                    app.SetFitButtons(0);
                    app.SetLights([0,0,0,0,0,0]);
                    app.SetButtons([1,0,0,0,0,0,1]);
                end
                app.SetStatus(2);

            end

        end % LoadMrdDataset


    end % methods (Access = public)


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app)

            % The window is already showing, so closing it during the startup waits for
            % the startup to finish, as it does during any other step
            app.busyFlag = true;
            app.ADCmappUIFigure.CloseRequestFcn = @(~,~) app.CloseAppFcn;

            % Disable unnecessary warnings
            clc;
            app.oldWarningState = warning;   % put back when the app closes
            warning off;

            % Set app theme to light
            theme(app.ADCmappUIFigure,"light")

            % Main window noname
            app.ADCmappUIFigure.Name = '';

            % Add subfolders to search folder
            if ~isdeployed
                app.oldFolder = pwd;
                info = dir(which('ADCmapp.mlapp'));
                app.appFolder = info.folder;
                cd(app.appFolder);
                addpath(genpath(pwd));

                % Retired copies live in backup/ and must not be reachable.
                % genpath walks folders in alphabetical order, so backup comes
                % before functions and an old copy there would be found first.
                if isfolder(fullfile(pwd,'backup'))
                    rmpath(genpath(fullfile(pwd,'backup')));
                end
            end

            % The dataset this session works on. Created here rather than
            % earlier because @adcData sits beside the app, so the class is only
            % reachable once the paths above are set.
            app.td = adcData;

            % The log first, so that anything that goes wrong from here on is
            % recorded, then the settings the interface starts from. Neither
            % raises: without a log the session is not logged, and a damaged
            % settings file is repaired or replaced.
            [app.logFileName, app.logFile] = adcmapp.io.makeLogFile(app.appVersion, AppReporter(app));
            app.TextMessage(strcat("ADCmapp ", app.appVersion, ", log file = ", app.logFileName, " ..."));
            app.jsonPar = adcmapp.io.readSettingsFile(app.appVersion, AppReporter(app));
            app.importFolder = app.jsonPar.importPath;
            app.gradFolder = app.jsonPar.gradientTablePath;
            app.dicomExportFolder = app.jsonPar.defaultExportPath;
            app.mrdExportFolder = app.jsonPar.defaultExportPath;
            app.gifExportFolder = app.jsonPar.defaultExportPath;
            app.roiExportFolder = app.jsonPar.defaultExportPath;

            % Initialize user interface
            app.InitializeUI;

            % Set app colors
            app.ColorsButtonGroupSelectionChanged;

            % PC or MAC
            try
                if ismac()
                    [~,result] = system('uname -v');
                    if any(strfind(result,'ARM64'))
                        app.TextMessage('Apple arm64 version ...');
                    else
                        app.TextMessage('Apple Intel version ...');
                    end
                else
                    app.TextMessage('PC Intel version ...');
                end
            catch ME
                app.TextMessage(ME.message);
            end

            % Elastix. The binary is located with resourcePath rather than which(),
            % because a compiled app has no MATLAB path.
            try
                if ismac || isunix
                    result = getenv('PATH');
                    for extra = {'/usr/local/bin','/usr/local/lib'}
                        if ~contains(result, extra{1})
                            result = [result pathsep extra{1}]; %#ok<AGROW>
                        end
                    end
                    setenv('PATH', result);
                else
                    elastixExe = adcmapp.util.resourcePath('elastix.exe');
                    if ~isempty(elastixExe)
                        elastixFolder = fileparts(elastixExe);
                        result = getenv('PATH');
                        if ~contains(result, elastixFolder)
                            setenv('PATH', [result pathsep elastixFolder]);
                        end
                    end
                end
            catch ME
                app.TextMessage(ME.message);
            end

            % Whether elastix can actually be run, reported once
            app.elastixDetectedFlag = false;
            try
                probe = adcmapp.util.useBundledElastix('elastix --version');
                [elastixStatus, elastixReply] = system(probe);
                app.elastixDetectedFlag = elastixStatus == 0 && contains(elastixReply,'version');
            catch
            end

            if app.elastixDetectedFlag
                app.TextMessage('Elastix detected ...');
            else
                app.TextMessage('WARNING: elastix not available; registration will use Matlab imregister ...');
            end

            % Old logs, now that the number to keep is known
            adcmapp.io.pruneLogFiles(app.logFileName, app.jsonPar.logFilesToKeep, AppReporter(app));

            % The final MATLAB version: no version check, since later versions are the
            % Python apps (the owner, 2026-10-02). CheckVersionFnc is kept, not called
            app.TextMessage("This is the final MATLAB version of ADCmapp (" + app.appVersion + ") ...");
            app.TextMessage("Later versions are Python apps: https://github.com/Moby1971/MRI-apps ...");

            % OK
            app.SetStatus(0);
            app.SetButtons([1,0,0,0,0,0,1]);
            app.TextMessage("Ready ...");

        end

        % Selection changed function: ColorsButtonGroup
        function ColorsButtonGroupSelectionChanged(app, event)

            selectedButton = app.ColorsButtonGroup.SelectedObject;

            switch lower(selectedButton.Text)

                % COLORS: backgroundColor, fontColor, boxColor, gaugeColor, gaugeBackgroundColor, figureColor, movieFigureColor, gridColor, autoRecoColor, manualRecoColor

                case "classic"
                    guiColors = {app.guiGrey, app.guiBlack, app.guiDarkGrey, app.blue, app.guiWhite, app.guiWhite, app.guiMiddleGrey, app.guiDarkGrey, app.lightblue, app.guiGrey};

                case "black/white"
                    guiColors = {app.guiBlack, app.guiWhite, app.guiDarkGrey, app.blue, app.guiDarkGrey, app.guiGrey, app.guiDarkGrey, app.guiDarkGrey, app.guiBlackBlue, app.guiBlack};

            end

            app.SetAppColorsFcn(guiColors);

        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)

            % Select an MRD file of a scan or a DICOM file of a series: an MRD scan is
            % opened by its file, as in P2ROUD, T1mapp and T2mapp, so the chosen file
            % decides even in a folder of several scans (the owner, 2026-09-30, as the
            % Python port does). The folder dialog it replaces felt odd for an MRD scan
            f = figure('CloseRequestFcn','','Position',[-100 -100 0 0]); % Needed to bring uigetfile in front
            startFolder = app.importFolder;
            if ~isempty(startFolder) && isfolder(startFolder) && ~endsWith(startFolder, filesep)
                startFolder = [startFolder filesep];
            end
            [fileName, folder] = uigetfile({'*.MRD;*.mrd;*.dcm;*.DCM','MRD or DICOM files'; '*.*','All files'}, ...
                'MRD file of a scan, or a DICOM file of a series ...', startFolder);
            delete(f);
            if ~app.closeRequested
                figure(app.ADCmappUIFigure);
            end

            if ~ischar(fileName)
                app.TextMessage('User cancel ...');
                return
            end

            app.importFolder = folder;

            % What the file stands for decides the route: an MRD scan is
            % reconstructed and then read, a DICOM series is read as it stands,
            % and another file falls back to what its folder holds, with what was
            % looked for when that is neither (adcmapp.io.loadFileTarget)
            [kind, target, reason] = adcmapp.io.loadFileTarget(fullfile(folder, fileName));

            switch kind

                case "mrd"
                    % Nothing is asked or written at the load: the export button writes
                    % the reconstruction (the owner, 2026-09-30, "Option C")
                    app.LoadMrdDataset(target);

                case "dicom"
                    app.LoadDataset(target);

                otherwise
                    % Nothing was loaded, so the lamps of the dataset held stay as they
                    % are: they all turned red while it stayed usable (A3)
                    app.TextMessage("ERROR: " + reason + " ...");
                    app.SetStatus(2);

            end

        end

        % Value changed function: TEViewField
        function TEViewFieldValueChanged(app, event)

            % The field is read-only and shows the echo time of the dataset; should it
            % ever be edited, the dataset's value is shown again
            if app.validDataFlag
                app.MirrorDataOutputsFcn;
            end

        end

        % Value changed function: ImageEditField, SlicesEditField
        function SlicesEditFieldValueChanged(app, event)

            if ~app.validDataFlag
                return
            end

            app.SlicesSlider.Value = app.SlicesEditField.Value;
            app.ImageSlider.Value = app.ImageEditField.Value;
            app.ShowImageInfoFcn;
            app.PlotImageFig;

        end

        % Value changed function: SlicesSlider
        function SlicesSliderValueChanged(app, event)

            app.SlicesEditField.Value = min(round(app.SlicesSlider.Value), app.SlicesEditField.Limits(2));
            app.SlicesEditFieldValueChanged;

        end

        % Value changed function: ImageSlider
        function ImageSliderValueChanged(app, event)

            app.ImageEditField.Value = min(round(app.ImageSlider.Value), app.ImageEditField.Limits(2));
            app.SlicesEditFieldValueChanged;

        end

        % Button pushed function: ImportGradTableButton
        function ImportGradTableButtonPushed(app, event)

            % Replace the b-values and gradient directions with a table from a file

            if ~app.validDataFlag
                app.TextMessage('Load a dataset before importing a gradient table ...');
                return
            end

            app.ADCmappUIFigure.Visible = 'off';
            [gradFile, folder] = uigetfile(fullfile(app.gradFolder,'*.txt'),'Gradient table ...');
            if ~app.closeRequested
                app.ADCmappUIFigure.Visible = 'on';
            end
            drawnow;

            if ~ischar(gradFile)
                app.TextMessage('User cancel ...');
                return
            end

            try

                app.gradFolder = folder;
                [bValues, gradients] = adcmapp.io.readGradientTable(fullfile(folder, gradFile));
                app.td.setGradientTable(bValues, gradients);
                app.ShowImageInfoFcn;

                app.TextMessage('Import of b-value & gradient table successful ...');
                app.TextMessage(strcat("B-values = ", num2str(unique(app.td.bValues)'), " ..."));

                if app.validFitFlag
                    app.TextMessage('WARNING: the maps were fitted with the previous b-values; fit again to use the table ...');
                    app.SetStatus(1);
                else
                    app.SetStatus(0);
                end

            catch ME

                app.TextMessage(ME.message);
                app.TextMessage('ERROR: the gradient table was not used ...');
                app.SetStatus(2);

            end

        end

        % Button pushed function: MaskButton
        function MaskButtonPushed(app, event)

            if app.validDataFlag

                % Indicate busy
                app.SetLights([2,2,3,0,0,0]);
                app.SetButtons([0,0,0,0,0,0,0]);
                app.SetFitButtons(0);
                app.SetImageSelectionButtons(0);
                app.SetSegmentationButtons(0);
                app.SetMapButtons(0);
                app.SetStatus(3);
                app.TextMessage('Thresholding images ...');

                % A failure halfway would leave a mask that no longer matches its
                % thresholds, so both are put back as they were
                previousMask = app.td.mask;
                previousThreshold = app.td.threshold;

                try

                    % An Otsu threshold per slice, on the reference image of the
                    % series, which shows the anatomy best
                    ref = adcmapp.fit.referenceIndex(app.td);

                    for slice = 1:app.td.ns
                        im = reshape(app.td.images(ref,slice,:,:), app.td.dimx, app.td.dimy);
                        app.td.threshold(slice) = adcmapp.fit.otsuThreshold(im);
                        app.td.mask(slice,:,:) = reshape(adcmapp.fit.applyThreshold(im, app.td.threshold(slice)), 1, app.td.dimx, app.td.dimy);
                    end

                    % Display the mask in the image
                    app.ImageEditField.Value = ref;
                    app.ImageSlider.Value = ref;
                    app.ShowImageInfoFcn;
                    app.PlotImageFig;

                    app.SetLights([2,2,1,0,0,0]);
                    app.SetButtons([1,1,1,1,0,0,1]);
                    app.SetFitButtons(1);
                    app.SetImageSelectionButtons(1);
                    app.SetSegmentationButtons(1);
                    app.SetMapButtons(double(app.validFitFlag));
                    app.SetStatus(0);

                catch ME

                    app.td.mask = previousMask;
                    app.td.threshold = previousThreshold;
                    app.TextMessage(ME.message);
                    app.TextMessage('ERROR: Something went wrong while thresholding the images ...');
                    app.SetLights([2,2,0,0,0,0]);
                    app.SetButtons([1,1,1,1,0,0,1]);
                    app.SetFitButtons(1);
                    app.SetImageSelectionButtons(1);
                    app.SetSegmentationButtons(1);
                    app.SetMapButtons(double(app.validFitFlag));
                    app.SetStatus(2);

                end

            end

        end

        % Value changed function: ThresholdEditField
        function ThresholdEditFieldValueChanged(app, event)

            % The threshold of the slice shown, and its mask, on the reference image.
            % Version 2.5 set every slice to the value typed for one.

            if ~app.validDataFlag
                return
            end

            % A failure halfway would leave a mask that is partly changed, so the mask
            % and thresholds are put back as they were
            previousMask = app.td.mask;
            previousThreshold = app.td.threshold;

            try

                slice = app.SlicesEditField.Value;
                app.td.threshold(slice) = app.ThresholdEditField.Value;
                app.ThresholdSlider.Value = app.ThresholdEditField.Value;

                im = reshape(app.td.images(adcmapp.fit.referenceIndex(app.td),slice,:,:), app.td.dimx, app.td.dimy);
                app.td.mask(slice,:,:) = reshape(adcmapp.fit.applyThreshold(im, app.td.threshold(slice)), 1, app.td.dimx, app.td.dimy);

                app.PlotImageFig;

            catch ME

                app.td.mask = previousMask;
                app.td.threshold = previousThreshold;
                app.TextMessage(ME.message);
                app.TextMessage('ERROR: the mask could not be changed ...');
                app.SetStatus(2);

            end

        end

        % Value changed function: ThresholdSlider
        function ThresholdSliderValueChanged(app, event)

            app.ThresholdEditField.Value = app.ThresholdSlider.Value;
            app.ThresholdEditFieldValueChanged;

        end

        % Button pushed function: ErodeButton
        function ErodeButtonPushed(app, event)

            app.MaskOperationFcn("erode");

        end

        % Button pushed function: DilateButton
        function DilateButtonPushed(app, event)

            app.MaskOperationFcn("dilate");

        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)

            app.MaskOperationFcn("close");

        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)

            app.MaskOperationFcn("open");

        end

        % Button pushed function: UndoButton
        function UndoButtonPushed(app, event)

            % Only the last operation is undone, and only while the mask is still the
            % one it left, so a threshold or a mask made since then is not overwritten
            if ~isempty(app.maskAfterOperation) && isequal(app.td.mask == 1, app.maskAfterOperation)
                app.td.mask = double(app.maskBeforeOperation);
                app.TextMessage('The last mask operation is undone ...');
                app.PlotImageFig;
            else
                app.TextMessage('WARNING: the mask has changed since the last operation, nothing to undo ...');
            end

            app.maskBeforeOperation = [];
            app.maskAfterOperation = [];
            app.UndoButton.Enable = 'off';

        end

        % Value changed function: WindowEditField
        function WindowEditFieldValueChanged(app, event)

            app.WindowSlider.Value = app.WindowEditField.Value;
            app.AutoLWButton.Value = 0;
            app.PlotImageFig;

        end

        % Value changed function: WindowSlider
        function WindowSliderValueChanged(app, event)

            app.WindowEditField.Value = round(app.WindowSlider.Value);
            app.AutoLWButton.Value = 0;
            app.PlotImageFig;

        end

        % Value changed function: LevelEditField
        function LevelEditFieldValueChanged(app, event)

            app.LevelSlider.Value = app.LevelEditField.Value;
            app.AutoLWButton.Value = 0;
            app.PlotImageFig;

        end

        % Value changed function: LevelSlider
        function LevelSliderValueChanged(app, event)

            app.LevelEditField.Value = round(app.LevelSlider.Value);
            app.AutoLWButton.Value = 0;
            app.PlotImageFig;

        end

        % Value changed function: AutoLWButton
        function AutoLWButtonValueChanged(app, event)

            if app.AutoLWButton.Value == 1
                app.PlotImageFig;
            end

        end

        % Button pushed function: RegisterButton
        function RegisterButtonPushed(app, event)

            if app.validDataFlag

                % What a failure puts back: the images, the maps, mask and thresholds
                % setImages clears, the flags that describe them, and the lamps
                previous.images = app.td.images;
                previous.adcmap = app.td.adcmap;
                previous.m0map = app.td.m0map;
                previous.r2map = app.td.r2map;
                previous.mask = app.td.mask;
                previous.threshold = app.td.threshold;
                previous.validRegFlag = app.validRegFlag;
                previous.validFitFlag = app.validFitFlag;
                previous.lamps = {app.Lamp1.Color, app.Lamp2.Color, app.Lamp3.Color, app.Lamp4.Color, ...
                    app.Lamp5.Color, app.Lamp6.Color};

                app.validRegFlag = false;
                app.validFitFlag = false;

                % Indicate busy
                app.SetLights([1,3,0,0,0,0]);
                app.SetButtons([0,0,0,0,0,0,0]);
                app.SetFitButtons(0);
                app.SetImageSelectionButtons(0);
                app.SetSegmentationButtons(0);
                app.SetMapButtons(0);
                app.SetStatus(3);
                app.abortRegFlag = false;
                app.AbortRegButton.Enable = 'on';

                try

                    % Every image of the series to the reference image of its slice
                    reporter = AppReporter(app,'registration');
                    regParams = gatherRegParams(app);
                    app.LogParamsFcn('registration', regParams);
                    [images, usedFallback] = adcmapp.reg.registerImages(app.td.images, ...
                        adcmapp.fit.referenceIndex(app.td), regParams, reporter);

                    app.td.setImages(images);

                    if usedFallback
                        app.TextMessage("WARNING: elastix was not available; registration used Matlab imregister ...");
                    end

                    if reporter.aborted()
                        app.TextMessage("WARNING: registration aborted, the images are partly registered ...");
                    end

                    app.validRegFlag = true;

                    % The signal under the cross-hair or ROI was that of the images before the
                    % registration; the next fit draws it again from these (A9)
                    app.datapoints = [];
                    cla(app.ADCMapFig);
                    cla(app.M0MapFig);
                    cla(app.R2MapFig);
                    app.PlotImageFig;

                    % Reset buttons
                    app.SetImageSelectionButtons(1);
                    app.SetSegmentationButtons(1);
                    app.SetFitButtons(1);
                    app.AbortRegButton.Enable = 'off';
                    app.SetLights([1,1,0,0,0,0]);
                    app.SetButtons([1,1,1,1,0,0,1]);
                    app.SetStatus(0);

                catch ME

                    app.TextMessage(ME.message);
                    app.TextMessage('ERROR: Something went wrong during registration ...');

                    % Everything back as it was before the step, so a registration that
                    % failed halfway leaves nothing half done, and the steps whose results
                    % are intact stay usable
                    app.td.images = previous.images;
                    app.td.adcmap = previous.adcmap;
                    app.td.m0map = previous.m0map;
                    app.td.r2map = previous.r2map;
                    app.td.mask = previous.mask;
                    app.td.threshold = previous.threshold;
                    app.validRegFlag = previous.validRegFlag;
                    app.validFitFlag = previous.validFitFlag;
                    for k = 1:numel(previous.lamps)
                        app.("Lamp" + k).Color = previous.lamps{k};
                    end
                    app.Lamp2.Color = app.red;

                    try
                        app.PlotImageFig;
                        app.PlotMaps;
                    catch
                    end

                    fitted = double(app.validFitFlag);
                    app.SetImageSelectionButtons(1);
                    app.SetSegmentationButtons(1);
                    app.SetFitButtons(1);
                    app.AbortRegButton.Enable = 'off';
                    app.SetMapButtons(fitted);
                    app.SetButtons([1,1,1,1,fitted,fitted,1]);
                    app.SetStatus(2);

                end

            end

        end

        % Button pushed function: AbortRegButton
        function AbortRegButtonPushed(app, event)

            app.abortRegFlag = true;
            drawnow;

        end

        % Button pushed function: FitADCButton
        function FitADCButtonPushed(app, event)

            if app.validDataFlag

                % What a failure puts back: the maps a partial fit overwrites, the flag
                % that describes them, the scale the fit sets, and the lamps
                previous.adcmap = app.td.adcmap;
                previous.m0map = app.td.m0map;
                previous.r2map = app.td.r2map;
                previous.validFitFlag = app.validFitFlag;
                previous.scale = [app.ADCScaleMinEditField.Value, app.ADCScaleEditField.Value];
                previous.lamps = {app.Lamp1.Color, app.Lamp2.Color, app.Lamp3.Color, app.Lamp4.Color, ...
                    app.Lamp5.Color, app.Lamp6.Color};

                app.SetLights([2,2,2,3,0,0]);
                app.SetButtons([0,0,0,0,0,0,0]);
                app.SetFitButtons(0);
                app.SetImageSelectionButtons(0);
                app.SetSegmentationButtons(0);
                app.SetMapButtons(0);
                app.SetStatus(3);
                app.TabGroup3.SelectedTab = app.ADCmapTab;
                app.TextMessage('Fitting ADC maps ...');
                app.validFitFlag = false;
                app.abortFitFlag = false;
                app.AbortFitButton.Enable = 'on';
                drawnow;

                try

                    % One struct describes this fit, gathered once, so that nothing
                    % below the app reads a widget
                    fitParams = gatherFitParams(app);
                    app.LogParamsFcn('fit', fitParams);

                    app.MakeSelectionFcn;
                    selected = app.td.bValues(app.td.bSelection);

                    if numel(unique(selected)) < adcmapp.Constants.MIN_B_VALUES
                        ME = MException('ADCmapp:SelectionError', ...
                            'ERROR: at least %d different b-values must be selected for fitting ...', ...
                            adcmapp.Constants.MIN_B_VALUES);
                        throw(ME);
                    end

                    if max(selected) - min(selected) < adcmapp.Constants.MIN_B_RANGE
                        ME = MException('ADCmapp:SelectionError', ...
                            'ERROR: the selected b-values spread over %g s/mm2, and a fit needs at least %g ...', ...
                            max(selected) - min(selected), adcmapp.Constants.MIN_B_RANGE);
                        throw(ME);
                    end

                    % Every pixel of the background is fitted too, and the
                    % automatic scale is set from all of them
                    if all(app.td.mask(:) == 1)
                        app.TextMessage('WARNING: there is no mask, so the background is fitted as well ...');
                    end

                    app.TextMessage(strcat("Fitting with the b-values ", num2str(selected'), " ..."));

                    % The slices to fit are cleared first: an aborted re-fit left the
                    % slices it never reached with the previous fit's maps, all marked
                    % valid (as T2mapping's F39; found by the Python port, A2)
                    slices = app.FirstSliceEditField.Value:app.LastSliceEditField.Value;
                    app.td.adcmap(slices,:,:) = 0;
                    app.td.m0map(slices,:,:) = 0;
                    app.td.r2map(slices,:,:) = 0;

                    app.FitSlicesFcn(fitParams);

                    if app.abortFitFlag
                        app.TextMessage('WARNING: fit aborted, the maps are incomplete ...');
                    else
                        app.TextMessage('Finished ...');
                    end

                    % Show the middle slice
                    app.validFitFlag = true;
                    app.ChangeMapSlices(round(app.td.ns/2));
                    app.AutoScaleFcn;
                    app.PlotMaps;

                    % The signal and curves under the cross-hair or ROI of the images and
                    % maps now held: they stayed those of before, and so did what the ROI
                    % export wrote (found by the Python port, A9)
                    app.PlotFits;

                    % Reset buttons
                    app.AbortFitButton.Enable = 'off';
                    app.SetImageSelectionButtons(1);
                    app.SetSegmentationButtons(1);
                    app.SetFitButtons(1);
                    app.SetLights([2,2,2,1,0,0]);
                    app.SetMapButtons(1);
                    app.SetButtons([1,1,1,1,1,1,1]);
                    app.SetStatus(0);

                catch ME

                    app.TextMessage(ME.message);
                    app.TextMessage("ERROR: Something went wrong during fitting ...");

                    % The maps back as they were before the step, so a fit that failed
                    % halfway leaves no mix of new and old slices, and an earlier fit that
                    % was complete stays usable
                    app.td.adcmap = previous.adcmap;
                    app.td.m0map = previous.m0map;
                    app.td.r2map = previous.r2map;
                    app.validFitFlag = previous.validFitFlag;
                    app.ADCScaleMinEditField.Value = previous.scale(1);
                    app.ADCScaleEditField.Value = previous.scale(2);
                    for k = 1:numel(previous.lamps)
                        app.("Lamp" + k).Color = previous.lamps{k};
                    end
                    app.Lamp4.Color = app.red;

                    try
                        if app.validFitFlag
                            app.PlotMaps;
                        else
                            cla(app.ADCMapFig);
                            cla(app.M0MapFig);
                            cla(app.R2MapFig);
                        end
                    catch
                    end

                    fitted = double(app.validFitFlag);
                    app.AbortFitButton.Enable = 'off';
                    app.SetImageSelectionButtons(1);
                    app.SetSegmentationButtons(1);
                    app.SetFitButtons(1);
                    app.SetMapButtons(fitted);
                    app.SetButtons([1,1,1,1,fitted,fitted,1]);
                    app.SetStatus(2);

                end

            end

        end

        % Button pushed function: AbortFitButton
        function AbortFitButtonPushed(app, event)

            app.abortFitFlag = true;
            drawnow;

        end

        % Value changed function: FirstSliceEditField, LastSliceEditField
        function FirstSliceEditFieldValueChanged(app, event)

            if app.FirstSliceEditField.Value > app.LastSliceEditField.Value
                app.LastSliceEditField.Value = app.FirstSliceEditField.Value;
            end

        end

        % Value changed function: ADCMapSlicesEditField
        function ADCMapSlicesEditFieldValueChanged(app, event)

            app.ChangeMapSlices(app.ADCMapSlicesEditField.Value);
            app.PlotMaps;

        end

        % Value changed function: M0MapSlicesEditField
        function M0MapSlicesEditFieldValueChanged(app, event)

            app.ChangeMapSlices(app.M0MapSlicesEditField.Value);
            app.PlotMaps;

        end

        % Value changed function: ADCMapSlicesSlider
        function ADCMapSlicesSliderValueChanged(app, event)

            app.ChangeMapSlices(app.ADCMapSlicesSlider.Value);
            app.PlotMaps;

        end

        % Value changed function: M0MapSlicesSlider
        function M0MapSlicesSliderValueChanged(app, event)

            app.ChangeMapSlices(app.M0MapSlicesSlider.Value);
            app.PlotMaps;

        end

        % Value changed function: ADCScaleEditField
        function ADCScaleEditFieldValueChanged(app, event)

            if app.ADCScaleEditField.Value <= app.ADCScaleMinEditField.Value
                app.ADCScaleEditField.Value = event.PreviousValue;
                app.TextMessage('WARNING: the ADC scale maximum has to lie above the minimum ...');
                return
            end

            app.PlotMaps;

        end

        % Value changed function: ADCScaleMinEditField
        function ADCScaleMinEditFieldValueChanged(app, event)

            if app.ADCScaleMinEditField.Value >= app.ADCScaleEditField.Value
                app.ADCScaleMinEditField.Value = event.PreviousValue;
                app.TextMessage('WARNING: the ADC scale minimum has to lie below the maximum ...');
                return
            end

            app.PlotMaps;

        end

        % Button pushed function: ADCScaleAutoButton
        function ADCScaleAutoButtonPushed(app, event)

            app.AutoScaleFcn;
            app.PlotMaps;

        end

        % Selection changed function: ADCColormapButtonGroup
        function ADCColormapButtonGroupSelectionChanged2(app, event)

            app.adccmap = app.ColorMapFromButtonsFcn(1, app.adccmap);
            app.PlotMaps;

        end

        % Selection changed function: M0ColorMapButtonGroup
        function M0ColorMapButtonGroupSelectionChanged(app, event)

            app.m0cmap = app.ColorMapFromButtonsFcn(2, app.m0cmap);
            app.PlotMaps;

        end

        % Selection changed function: R2ColorMapButtonGroup
        function R2ColorMapButtonGroupSelectionChanged(app, event)

            app.r2cmap = app.ColorMapFromButtonsFcn(3, app.r2cmap);
            app.PlotMaps;

        end

        % Image clicked function: ADCImage
        function ADCImageClicked(app, event)

            if app.validFitFlag

                % The position of the app and of the mouse click
                set(0,'units','pixels');
                guiposition = get(app.ADCmappUIFigure,'Position');
                cursorposition = get(0,'PointerLocation');

                % Position of the click in the image window, whose bottom-left corner
                % sits at (862, 412) in the app and which is 313 pixels square
                imagepos = [862,412];
                imsize = 313;
                Xpos = cursorposition(1)-guiposition(1)-imagepos(1);
                Ypos = cursorposition(2)-guiposition(2)-imagepos(2);

                aratio = app.td.fovRatio;
                xsize = app.td.dimx;
                ysize = app.td.dimy;

                % Correct for a non-square image
                if aratio >= 1
                    xcor = (Xpos/imsize) * xsize;
                    ycor = (Ypos/imsize) * ysize * aratio - (0.5 * (aratio-1) * ysize);
                else
                    xcor = (Xpos/imsize) * xsize * (1/aratio) - (0.5 * ((1/aratio)-1) * xsize);
                    ycor = (Ypos/imsize) * ysize;
                end

                % Within the matrix
                xcor = min(max(round(xcor),1),xsize);
                ycor = min(max(round(ycor),1),ysize);
                zcor = app.ADCMapSlicesEditField.Value;
                app.XViewField.Value = xcor;
                app.YViewField.Value = ycor;

                if app.roiFlag

                    % Paint the points within the brush radius
                    % The exact disk, 2r+1 wide, centred on the click: the offset was one
                    % short, so brushes 1-3 painted beside it (as T2mapping's F8; found by
                    % the Python port, A4)
                    se = strel("disk",app.ADCBrushEditField.Value-1,0);
                    [nzrow,nzcol] = find(se.Neighborhood==1);
                    nzc = [nzrow,nzcol] - (size(se.Neighborhood)+1)/2;

                    for i = 1:size(nzc,1)
                        if (xcor+nzc(i,1)>0) && (ycor+nzc(i,2)>0) && (xcor+nzc(i,1)<=xsize) && (ycor+nzc(i,2)<=ysize)
                            app.roiPoints = [app.roiPoints; [xcor+nzc(i,1) ycor+nzc(i,2) zcor]];
                        end
                    end

                    app.roiPoints = unique(app.roiPoints,'rows');
                    plotPoints = app.roiPoints(app.roiPoints(:,3)==zcor, :);
                    app.PlotPoint(plotPoints(:,1),plotPoints(:,2),ysize);

                else

                    % A cross-hair
                    app.roiPoints = [xcor ycor zcor];
                    app.PlotCross(xcor,ycor,ysize);

                end

                % The fit in the chosen pixel or ROI
                app.PlotFits;

            end

        end

        % Image clicked function: M0Image
        function M0ImageClicked(app, event)

            app.ADCImageClicked;

        end

        % Button pushed function: ADCROIbutton, M0ROIbutton
        function ROIbuttonButtonPushed(app, event)

            % Toggle between ROI drawing and the cross-hair

            app.roiFlag = strcmp(app.ADCROIbutton.Text,'ROI');

            if app.roiFlag
                app.ADCROIbutton.Text = 'Cross';
                app.M0ROIbutton.Text = 'Cross';
                app.TextMessage('ROI drawing selected ...');
            else
                app.ADCROIbutton.Text = 'ROI';
                app.M0ROIbutton.Text = 'ROI';
                app.TextMessage('Cross-hair selected ...');
            end

            visible = matlab.lang.OnOffSwitchState(app.roiFlag);
            app.ADCEraseButton.Visible = visible;
            app.M0EraseButton.Visible = visible;
            app.ADCBrushEditField.Visible = visible;
            app.ADCBrushEditFieldLabel.Visible = visible;
            app.M0BrushEditField.Visible = visible;
            app.M0BrushEditFieldLabel.Visible = visible;

            app.roiPoints = [];
            app.datapoints = [];
            delete(app.roiMD);
            delete(app.roiM0);
            delete(app.crossMD);
            delete(app.crossM0);
            % The signal plots and the ADC field go with the ROI: they showed the
            % cleared ROI's signal while the export said there was none (the owner's
            % decision on the Python port's A81)
            cla(app.ADClinearPlot);
            cla(app.ADClogPlot);
            app.ADCViewField.Value = 0;
            app.PlotMaps;

        end

        % Value changed function: ADCBrushEditField
        function ADCBrushEditFieldValueChanged(app, event)

            app.M0BrushEditField.Value = app.ADCBrushEditField.Value;

        end

        % Value changed function: M0BrushEditField
        function M0BrushEditFieldValueChanged(app, event)

            app.ADCBrushEditField.Value = app.M0BrushEditField.Value;

        end

        % Button pushed function: ADCEraseButton, M0EraseButton
        function EraseButtonPushed(app, event)

            app.roiPoints = [];
            app.datapoints = [];
            delete(app.roiMD);
            delete(app.roiM0);
            % The signal plots and the ADC field go with the ROI: they showed the
            % cleared ROI's signal while the export said there was none (the owner's
            % decision on the Python port's A81)
            cla(app.ADClinearPlot);
            cla(app.ADClogPlot);
            app.ADCViewField.Value = 0;
            app.PlotMaps;

        end

        % Button pushed function: ADCexportRoiDataButton
        function ExportRoiDataButtonPushed(app, event)

            % Writes the b-values and the signal under the cross-hair or the ROI as a
            % CSV file

            if ~app.validFitFlag || isempty(app.datapoints)
                app.TextMessage('There is no ROI data to export: click in a map first ...');
                return
            end

            try

                app.ADCmappUIFigure.Visible = 'off';
                folder = uigetdir(app.roiExportFolder,'ROI data export folder ...');
                if ~app.closeRequested
                    app.ADCmappUIFigure.Visible = 'on';
                end

                if ~ischar(folder)
                    app.TextMessage('User cancel ...');
                    return
                end

                app.roiExportFolder = folder;
                rdata = [app.td.bValues(:), app.datapoints(:)];
                fileName = fullfile(app.roiExportFolder, strcat('roiData-', app.td.tag, '.csv'));
                writematrix(rdata, fileName);
                app.TextMessage(strcat("ROI data exported to ", fileName, " ..."));
                app.SetStatus(0);

            catch ME

                if ~app.closeRequested
                    app.ADCmappUIFigure.Visible = 'on';
                end
                app.TextMessage(ME.message);
                app.TextMessage('ERROR: the ROI data could not be exported ...');
                app.SetStatus(2);

            end

        end

        % Button pushed function: DCMNIFTIButton
        function DCMNIFTIButtonPushed(app, event)

            if app.validFitFlag

                % What a cancelled dialog or a failure puts back: the export folder, which
                % the settings may have set, and the lamp of this step
                previousFolder = app.dicomExportFolder;
                previousLamp = app.Lamp5.Color;

                % Indicate busy
                app.SetLights([2,2,2,2,3,2]);
                app.SetButtons([0,0,0,0,0,0,0]);
                app.SetFitButtons(0);
                app.SetImageSelectionButtons(0);
                app.SetSegmentationButtons(0);
                app.SetMapButtons(0);
                app.SetStatus(3);

                try

                    app.TextMessage('DICOM export ...');

                    % Beside the scanner's own files when the folder is one of theirs:
                    %   base / Data / study / DICOM / series / 1 /
                    % A scan read from MRD and not exported yet: the reconstruction goes
                    % into the study's Data folder beside the scanner's DICOM, as the load
                    % wrote it before 2026-09-30, or where the user says
                    mrdPending = ~isempty(app.td.mrdScan) && isempty(app.td.mrdExported);
                    if mrdPending
                        [~, baseFolder] = adcmapp.mrd.scannerDicom(fileparts(app.td.mrdFile));
                        baseFolder = regexprep(char(baseFolder), '[\\/]+$', '');
                        found = ~isempty(baseFolder);
                        dicomFolder = fullfile(baseFolder, 'DICOM');
                    else
                        [dicomFolder, baseFolder, found] = adcmapp.io.exportFolders(app.td.dicomFolder);
                    end

                    if ~found || ~app.FolderWritableFcn(dicomFolder)
                        app.ADCmappUIFigure.Visible = 'off';
                        folder = uigetdir(app.dicomExportFolder,'Export folder ...');
                        if ~app.closeRequested
                            app.ADCmappUIFigure.Visible = 'on';
                        end
                        drawnow;
                        if ~ischar(folder)
                            ME = MException('ADCmapp:userCancel','User cancel ...');
                            throw(ME);
                        end
                        app.dicomExportFolder = folder;
                        baseFolder = folder;
                        dicomFolder = fullfile(folder, 'DICOM');
                    end

                    if mrdPending
                        app.TextMessage('Writing the reconstruction: DICOM series, parameters, NIfTI, BIDS and scripts ...');
                        [seriesFolder, niftiFolder] = app.td.exportMrd(baseFolder, AppReporter(app));
                        app.TextMessage(strcat("Reconstruction written to ", seriesFolder, " ..."));
                        app.MirrorDataOutputsFcn;
                        if isempty(niftiFolder)
                            app.TextMessage('WARNING: no NIfTI was written for the reconstruction ...');
                        end
                    end

                    app.TextMessage(strcat("DICOM export folder = ", dicomFolder, " ..."));
                    [mapFolder, sourceFolder] = adcmapp.io.exportDicomADC(app.td, dicomFolder, AppReporter(app));
                    app.TextMessage(strcat("Maps written to ", mapFolder, " ..."));
                    app.TextMessage(strcat("DICOM images copied to ", sourceFolder, " ..."));

                    % NIfTI of the maps and of the diffusion-weighted images
                    try
                        mapNifti = adcmapp.io.exportNifti(app.td, baseFolder, mapFolder, AppReporter(app));
                        sourceNifti = adcmapp.io.exportNifti(app.td, baseFolder, sourceFolder, AppReporter(app));
                        if isempty(mapNifti) || isempty(sourceNifti)
                            app.TextMessage('WARNING: part of this series was not written as NIfTI ...');
                        end
                    catch ME
                        app.TextMessage(ME.message);
                        app.TextMessage("ERROR: NIfTI export failed ...");
                    end

                    % Success
                    app.TextMessage('DICOM export ready ...');
                    app.SetFitButtons(1);
                    app.SetImageSelectionButtons(1);
                    app.SetSegmentationButtons(1);
                    app.SetLights([2,2,2,2,1,2]);
                    app.SetMapButtons(1);
                    app.SetButtons([1,1,1,1,1,1,1]);
                    app.SetStatus(0);

                catch ME

                    % The window is shown again in case the failure came while it was hidden
                    % behind the folder dialog, and the export folder is kept
                    if ~app.closeRequested
                        app.ADCmappUIFigure.Visible = 'on';
                    end
                    app.dicomExportFolder = previousFolder;
                    app.SetFitButtons(1);
                    app.SetImageSelectionButtons(1);
                    app.SetSegmentationButtons(1);
                    app.SetMapButtons(1);
                    app.SetButtons([1,1,1,1,1,1,1]);

                    if strcmpi(ME.identifier, 'ADCmapp:userCancel')
                        % Cancelling the folder dialog is not a failure
                        app.TextMessage('DICOM export cancelled ...');
                        app.Lamp5.Color = previousLamp;
                        app.SetStatus(0);
                    else
                        app.TextMessage(ME.message);
                        app.TextMessage('ERROR: Something went wrong during DICOM export ...');
                        app.SetLights([2,2,2,2,0,2]);
                        app.SetStatus(2);
                    end

                end

            end

        end

        % Button pushed function: GIFButton
        function GIFButtonPushed(app, event)

            if app.validFitFlag

                % What a cancelled dialog or a failure puts back: the export folder, which
                % the settings may have set, and the lamp of this step
                previousFolder = app.gifExportFolder;
                previousLamp = app.Lamp6.Color;

                % Indicate busy
                app.SetLights([2,2,2,2,2,3]);
                app.SetButtons([0,0,0,0,0,0,0]);
                app.SetFitButtons(0);
                app.SetImageSelectionButtons(0);
                app.SetSegmentationButtons(0);
                app.SetMapButtons(0);
                app.SetStatus(3);

                app.TextMessage('GIF export ...');

                try

                    % base / Data / study / GIF / when the folder is the scanner's; for a
                    % scan read from MRD and not exported, the study beside the scanner's
                    % DICOM, where the export goes (its series is in a temporary folder;
                    % found by the Python port, A103)
                    if ~isempty(app.td.mrdScan) && isempty(app.td.mrdExported)
                        [~, baseFolder] = adcmapp.mrd.scannerDicom(fileparts(app.td.mrdFile));
                        baseFolder = regexprep(char(baseFolder), '[\\/]+$', '');
                        found = ~isempty(baseFolder);
                    else
                        [~, baseFolder, found] = adcmapp.io.exportFolders(app.td.dicomFolder);
                    end

                    if ~found || ~app.FolderWritableFcn(baseFolder)
                        app.ADCmappUIFigure.Visible = 'off';
                        folder = uigetdir(app.gifExportFolder,'GIF export folder ...');
                        if ~app.closeRequested
                            app.ADCmappUIFigure.Visible = 'on';
                        end
                        if ~ischar(folder)
                            ME = MException('ADCmapp:userCancel','User cancel ...');
                            throw(ME);
                        end
                        app.gifExportFolder = folder;
                        baseFolder = folder;
                    end

                    app.ExportGifsFcn(baseFolder);
                    app.TextMessage('GIF export ready ...');

                    app.SetFitButtons(1);
                    app.SetImageSelectionButtons(1);
                    app.SetSegmentationButtons(1);
                    app.SetLights([2,2,2,2,2,1]);
                    app.SetMapButtons(1);
                    app.SetButtons([1,1,1,1,1,1,1]);
                    app.SetStatus(0);

                catch ME

                    % The window is shown again in case the failure came while it was hidden
                    % behind the folder dialog, and the export folder is kept
                    if ~app.closeRequested
                        app.ADCmappUIFigure.Visible = 'on';
                    end
                    app.gifExportFolder = previousFolder;
                    app.SetFitButtons(1);
                    app.SetImageSelectionButtons(1);
                    app.SetSegmentationButtons(1);
                    app.SetMapButtons(1);
                    app.SetButtons([1,1,1,1,1,1,1]);

                    if strcmpi(ME.identifier, 'ADCmapp:userCancel')
                        % Cancelling the folder dialog is not a failure
                        app.TextMessage('GIF export cancelled ...');
                        app.Lamp6.Color = previousLamp;
                        app.SetStatus(0);
                    else
                        app.TextMessage(ME.message);
                        app.TextMessage('ERROR: Something went wrong during GIF export ...');
                        app.SetLights([2,2,2,2,2,0]);
                        app.SetStatus(2);
                    end

                end

            end

        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)

            % The log closed, the path restored and the app deleted
            app.CloseAppFcn;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.ADCmappUIFigure = uifigure('Visible', 'off');
            app.ADCmappUIFigure.Name = 'ADCmapp';
            app.ADCmappUIFigure.Icon = fullfile(pathToMLAPP, 'ADCicon.gif');
            app.ADCmappingLabel = uilabel(app.ADCmappUIFigure);
            app.ADCmappingLabel.Text = 'ADC mapping';
            app.Credits = uilabel(app.ADCmappUIFigure);
            app.Credits.Tooltip = {'g.j.strijkers@amsterdamumc.nl'};
            app.Credits.Text = 'Gustav Strijkers, Amsterdam UMC, Version X.X.X';
            app.TulpLogo = uiimage(app.ADCmappUIFigure);
            app.MessageBox = uitextarea(app.ADCmappUIFigure);
            app.MessageBox.Editable = 'off';
            app.MessageBox.Tooltip = {'Messages'};
            app.MessageBox.Value = {'Messages ....'};
            app.BackgroundBox = uitextarea(app.ADCmappUIFigure);
            app.BackgroundBox.Editable = 'off';
            app.BackgroundBox.Tooltip = {'App status'};
            app.StatusImage = uiimage(app.ADCmappUIFigure);
            app.StatusLabel = uilabel(app.ADCmappUIFigure);
            app.StatusLabel.Text = 'STATUS';
            app.MRSImage = uiimage(app.ADCmappUIFigure);
            app.TabGroup1 = uitabgroup(app.ADCmappUIFigure);
            app.DashboardTab = uitab(app.TabGroup1);
            app.DashboardTab.Title = 'Dashboard';
            app.LoadButton = uibutton(app.DashboardTab, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Enable = 'off';
            app.LoadButton.Tooltip = {'Load a diffusion-weighted dataset: an MRD file of an MR Solutions EPI scan, reconstructed from its MRD files, or any DICOM file of a series'};
            app.LoadButton.Text = 'Load';
            app.Lamp1 = uilamp(app.DashboardTab);
            app.MaskButton = uibutton(app.DashboardTab, 'push');
            app.MaskButton.ButtonPushedFcn = createCallbackFcn(app, @MaskButtonPushed, true);
            app.MaskButton.Enable = 'off';
            app.MaskButton.Tooltip = {'Mask the images to exclude fitting of background pixels'};
            app.MaskButton.Text = 'Mask';
            app.Lamp3 = uilamp(app.DashboardTab);
            app.FitADCButton = uibutton(app.DashboardTab, 'push');
            app.FitADCButton.ButtonPushedFcn = createCallbackFcn(app, @FitADCButtonPushed, true);
            app.FitADCButton.Enable = 'off';
            app.FitADCButton.Tooltip = {'Start the ADC fitting'};
            app.FitADCButton.Text = 'Fit ADC';
            app.Lamp4 = uilamp(app.DashboardTab);
            app.FitProgressGauge = uigauge(app.DashboardTab, 'linear');
            app.FitProgressGauge.MajorTicks = [0 25 50 75 100];
            app.Lamp5 = uilamp(app.DashboardTab);
            app.ExitButton = uibutton(app.DashboardTab, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Enable = 'off';
            app.ExitButton.Tooltip = {'Exit the application'};
            app.ExitButton.Text = 'Exit';
            app.DCMNIFTIButton = uibutton(app.DashboardTab, 'push');
            app.DCMNIFTIButton.ButtonPushedFcn = createCallbackFcn(app, @DCMNIFTIButtonPushed, true);
            app.DCMNIFTIButton.Enable = 'off';
            app.DCMNIFTIButton.Tooltip = {'Export to DICOM and NIfTI'};
            app.DCMNIFTIButton.Text = 'DCM / NIFTI';
            app.FitProgressLabel = uilabel(app.DashboardTab);
            app.FitProgressLabel.Text = 'Progress';
            app.Lamp6 = uilamp(app.DashboardTab);
            app.GIFButton = uibutton(app.DashboardTab, 'push');
            app.GIFButton.ButtonPushedFcn = createCallbackFcn(app, @GIFButtonPushed, true);
            app.GIFButton.Enable = 'off';
            app.GIFButton.Tooltip = {'Export to animated GIF'};
            app.GIFButton.Text = 'GIF';
            app.AbortFitButton = uibutton(app.DashboardTab, 'push');
            app.AbortFitButton.ButtonPushedFcn = createCallbackFcn(app, @AbortFitButtonPushed, true);
            app.AbortFitButton.Enable = 'off';
            app.AbortFitButton.Tooltip = {'Abort the ADC fitting'};
            app.AbortFitButton.Text = 'Abort';
            app.RegisterButton = uibutton(app.DashboardTab, 'push');
            app.RegisterButton.ButtonPushedFcn = createCallbackFcn(app, @RegisterButtonPushed, true);
            app.RegisterButton.Enable = 'off';
            app.RegisterButton.Tooltip = {'Register the images to the first image of the lowest b-value'};
            app.RegisterButton.Text = 'Register';
            app.Lamp2 = uilamp(app.DashboardTab);
            app.RegProgressGauge = uigauge(app.DashboardTab, 'linear');
            app.RegProgressGauge.MajorTicks = [0 25 50 75 100];
            app.RegProgressLabel = uilabel(app.DashboardTab);
            app.RegProgressLabel.Text = 'Progress';
            app.RegistrationDropDown = uidropdown(app.DashboardTab);
            app.RegistrationDropDown.Items = {'Translation', 'Rigid', 'Affine', 'B-Spline'};
            app.RegistrationDropDown.Enable = 'off';
            app.RegistrationDropDown.Tooltip = {'Image registration transformation method'};
            app.RegistrationDropDown.Value = 'Translation';
            app.AbortRegButton = uibutton(app.DashboardTab, 'push');
            app.AbortRegButton.ButtonPushedFcn = createCallbackFcn(app, @AbortRegButtonPushed, true);
            app.AbortRegButton.Enable = 'off';
            app.AbortRegButton.Tooltip = {'Abort the registration'};
            app.AbortRegButton.Text = 'Abort';
            app.EstimatedRegTimeViewField = uieditfield(app.DashboardTab, 'text');
            app.EstimatedRegTimeViewField.Editable = 'off';
            app.EstimatedFitTimeViewField = uieditfield(app.DashboardTab, 'text');
            app.EstimatedFitTimeViewField.Editable = 'off';
            app.TabGroup3 = uitabgroup(app.ADCmappUIFigure);
            app.ADCmapTab = uitab(app.TabGroup3);
            app.ADCmapTab.Title = 'ADC map';
            app.ADCMapFig = uiaxes(app.ADCmapTab);
            app.ADCMapFig.XTick = [];
            app.ADCMapFig.YTick = [];
            app.ADClogPlot = uiaxes(app.ADCmapTab);
            xlabel(app.ADClogPlot, 'b')
            ylabel(app.ADClogPlot, 'log ( Signal )')
            app.ADClinearPlot = uiaxes(app.ADCmapTab);
            xlabel(app.ADClinearPlot, 'b')
            ylabel(app.ADClinearPlot, 'Signal')
            app.ADCMapLabel = uilabel(app.ADCmapTab);
            app.ADCMapLabel.Text = 'ADC map';
            app.ADCImageSelectionPanel = uipanel(app.ADCmapTab);
            app.ADCImageSelectionPanel.Title = 'Image selection';
            app.ADCMapSlicesSlider = uislider(app.ADCImageSelectionPanel);
            app.ADCMapSlicesSlider.Limits = [1 200];
            app.ADCMapSlicesSlider.MajorTicks = [];
            app.ADCMapSlicesSlider.ValueChangedFcn = createCallbackFcn(app, @ADCMapSlicesSliderValueChanged, true);
            app.ADCMapSlicesSlider.MinorTicks = [1 10 19 28 37 46 55 64 73 82 91 100 109 118 127 136 145 154 163 172 181 190 200];
            app.ADCMapSlicesSlider.Tooltip = {'Slice number slider'};
            app.ADCMapSlicesSlider.Value = 1;
            app.ADCMapSlicesEditFieldLabel = uilabel(app.ADCImageSelectionPanel);
            app.ADCMapSlicesEditFieldLabel.Text = 'slice';
            app.ADCMapSlicesEditField = uieditfield(app.ADCImageSelectionPanel, 'numeric');
            app.ADCMapSlicesEditField.Limits = [1 Inf];
            app.ADCMapSlicesEditField.RoundFractionalValues = 'on';
            app.ADCMapSlicesEditField.ValueDisplayFormat = '%.0f';
            app.ADCMapSlicesEditField.ValueChangedFcn = createCallbackFcn(app, @ADCMapSlicesEditFieldValueChanged, true);
            app.ADCMapSlicesEditField.Tooltip = {'Slice number'};
            app.ADCMapSlicesEditField.Value = 1;
            app.ADCColormapButtonGroup = uibuttongroup(app.ADCmapTab);
            app.ADCColormapButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ADCColormapButtonGroupSelectionChanged2, true);
            app.ADCColormapButtonGroup.Title = 'Color map';
            app.grayButton = uiradiobutton(app.ADCColormapButtonGroup);
            app.grayButton.Text = 'gray';
            app.grayButton.Interpreter = 'html';
            app.autumnButton = uiradiobutton(app.ADCColormapButtonGroup);
            app.autumnButton.Text = 'autumn';
            app.autumnButton.Interpreter = 'html';
            app.winterButton = uiradiobutton(app.ADCColormapButtonGroup);
            app.winterButton.Text = 'winter';
            app.winterButton.Interpreter = 'html';
            app.parulaButton = uiradiobutton(app.ADCColormapButtonGroup);
            app.parulaButton.Text = 'parula';
            app.parulaButton.Interpreter = 'html';
            app.naviaButton = uiradiobutton(app.ADCColormapButtonGroup);
            app.naviaButton.Text = 'navia';
            app.naviaButton.Interpreter = 'html';
            app.naviaButton.Value = true;
            app.ADCScaleEditField = uieditfield(app.ADCColormapButtonGroup, 'numeric');
            app.ADCScaleEditField.Limits = [0.1 Inf];
            app.ADCScaleEditField.ValueDisplayFormat = '%.2f';
            app.ADCScaleEditField.ValueChangedFcn = createCallbackFcn(app, @ADCScaleEditFieldValueChanged, true);
            app.ADCScaleEditField.Tooltip = {'Upper end of the ADC map scale'};
            app.ADCScaleEditField.Value = 1;
            app.ADCScaleMaxLabel = uilabel(app.ADCColormapButtonGroup);
            app.ADCScaleMaxLabel.Text = 'max';
            app.ADCScaleMinLabel = uilabel(app.ADCColormapButtonGroup);
            app.ADCScaleMinLabel.Text = 'min';
            app.ADCScaleMinEditField = uieditfield(app.ADCColormapButtonGroup, 'numeric');
            app.ADCScaleMinEditField.Limits = [0 Inf];
            app.ADCScaleMinEditField.ValueDisplayFormat = '%.2f';
            app.ADCScaleMinEditField.ValueChangedFcn = createCallbackFcn(app, @ADCScaleMinEditFieldValueChanged, true);
            app.ADCScaleMinEditField.Tooltip = {'Lower end of the ADC map scale'};
            app.ADCScaleAutoButton = uibutton(app.ADCColormapButtonGroup, 'push');
            app.ADCScaleAutoButton.ButtonPushedFcn = createCallbackFcn(app, @ADCScaleAutoButtonPushed, true);
            app.ADCScaleAutoButton.Tooltip = {'Automatic ADC map scale setting'};
            app.ADCScaleAutoButton.Text = 'Auto';
            app.ADCImage = uiimage(app.ADCmapTab);
            app.ADCImage.ImageClickedFcn = createCallbackFcn(app, @ADCImageClicked, true);
            app.XViewFieldLabel = uilabel(app.ADCmapTab);
            app.XViewFieldLabel.Text = 'X';
            app.XViewField = uieditfield(app.ADCmapTab, 'numeric');
            app.XViewField.ValueDisplayFormat = '%.0f';
            app.XViewField.Editable = 'off';
            app.XViewField.Tooltip = {'X-coordinate'};
            app.YViewFieldLabel = uilabel(app.ADCmapTab);
            app.YViewFieldLabel.Text = 'Y';
            app.YViewField = uieditfield(app.ADCmapTab, 'numeric');
            app.YViewField.ValueDisplayFormat = '%.0f';
            app.YViewField.Editable = 'off';
            app.YViewField.Tooltip = {'Y-coordinate'};
            app.ADCViewField = uieditfield(app.ADCmapTab, 'numeric');
            app.ADCViewField.ValueDisplayFormat = '%.2f';
            app.ADCViewField.Editable = 'off';
            app.ADCViewField.Tooltip = {'ADC value'};
            app.ADCViewFieldLabel = uilabel(app.ADCmapTab);
            app.ADCViewFieldLabel.Text = 'ADC ';
            app.ADCunitLabel1 = uilabel(app.ADCmapTab);
            app.ADCunitLabel1.Text = '10  mm /s';
            app.ADCunitLabel2 = uilabel(app.ADCmapTab);
            app.ADCunitLabel2.Text = '-3';
            app.ADCunitLabel3 = uilabel(app.ADCmapTab);
            app.ADCunitLabel3.Text = '2';
            app.ADCROIbutton = uibutton(app.ADCmapTab, 'push');
            app.ADCROIbutton.ButtonPushedFcn = createCallbackFcn(app, @ROIbuttonButtonPushed, true);
            app.ADCROIbutton.Tooltip = {'Cross-hair / ROI drawing'};
            app.ADCROIbutton.Text = 'ROI';
            app.ADCEraseButton = uibutton(app.ADCmapTab, 'push');
            app.ADCEraseButton.ButtonPushedFcn = createCallbackFcn(app, @EraseButtonPushed, true);
            app.ADCEraseButton.Visible = 'off';
            app.ADCEraseButton.Tooltip = {'Erase ROI'};
            app.ADCEraseButton.Text = 'Erase';
            app.ADCBrushEditFieldLabel = uilabel(app.ADCmapTab);
            app.ADCBrushEditFieldLabel.Visible = 'off';
            app.ADCBrushEditFieldLabel.Text = 'brush';
            app.ADCBrushEditField = uieditfield(app.ADCmapTab, 'numeric');
            app.ADCBrushEditField.Limits = [1 5];
            app.ADCBrushEditField.RoundFractionalValues = 'on';
            app.ADCBrushEditField.ValueDisplayFormat = '%.0f';
            app.ADCBrushEditField.ValueChangedFcn = createCallbackFcn(app, @ADCBrushEditFieldValueChanged, true);
            app.ADCBrushEditField.Visible = 'off';
            app.ADCBrushEditField.Tooltip = {'ROI brush size (pixels)'};
            app.ADCBrushEditField.Value = 2;
            app.LogLabel = uilabel(app.ADCmapTab);
            app.LogLabel.Text = 'Log';
            app.LinearLabel = uilabel(app.ADCmapTab);
            app.LinearLabel.Text = 'Linear';
            app.ADCexportRoiDataButton = uibutton(app.ADCmapTab, 'push');
            app.ADCexportRoiDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportRoiDataButtonPushed, true);
            app.ADCexportRoiDataButton.Tooltip = {'Export signal as function of b-value in ROI to CSV-file'};
            app.ADCexportRoiDataButton.Text = 'Export';
            app.M0R2Tab = uitab(app.TabGroup3);
            app.M0R2Tab.Title = 'M0 & R²';
            app.M0MapFig = uiaxes(app.M0R2Tab);
            app.M0MapFig.XTick = [];
            app.M0MapFig.YTick = [];
            app.R2MapFig = uiaxes(app.M0R2Tab);
            app.R2MapFig.XTick = [];
            app.R2MapFig.YTick = [];
            app.M0MapLabel = uilabel(app.M0R2Tab);
            app.M0MapLabel.Text = 'M0 map';
            app.R2MapLabel = uilabel(app.M0R2Tab);
            app.R2MapLabel.Text = 'R² map';
            app.M0ImageSelectionPanel = uipanel(app.M0R2Tab);
            app.M0ImageSelectionPanel.Title = 'Image selection';
            app.M0MapSlicesSlider = uislider(app.M0ImageSelectionPanel);
            app.M0MapSlicesSlider.Limits = [1 200];
            app.M0MapSlicesSlider.MajorTicks = [];
            app.M0MapSlicesSlider.ValueChangedFcn = createCallbackFcn(app, @M0MapSlicesSliderValueChanged, true);
            app.M0MapSlicesSlider.MinorTicks = [1 10 19 28 37 46 55 64 73 82 91 100 109 118 127 136 145 154 163 172 181 190 200];
            app.M0MapSlicesSlider.Tooltip = {'Slice number slider'};
            app.M0MapSlicesSlider.Value = 1;
            app.M0MapSlicesEditFieldLabel = uilabel(app.M0ImageSelectionPanel);
            app.M0MapSlicesEditFieldLabel.Text = 'slice';
            app.M0MapSlicesEditField = uieditfield(app.M0ImageSelectionPanel, 'numeric');
            app.M0MapSlicesEditField.Limits = [1 Inf];
            app.M0MapSlicesEditField.RoundFractionalValues = 'on';
            app.M0MapSlicesEditField.ValueDisplayFormat = '%.0f';
            app.M0MapSlicesEditField.ValueChangedFcn = createCallbackFcn(app, @M0MapSlicesEditFieldValueChanged, true);
            app.M0MapSlicesEditField.Tooltip = {'Slice number'};
            app.M0MapSlicesEditField.Value = 1;
            app.M0ColorMapButtonGroup = uibuttongroup(app.M0R2Tab);
            app.M0ColorMapButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @M0ColorMapButtonGroupSelectionChanged, true);
            app.M0ColorMapButtonGroup.Title = 'Color map';
            app.grayButton2 = uiradiobutton(app.M0ColorMapButtonGroup);
            app.grayButton2.Text = 'gray';
            app.grayButton2.Interpreter = 'html';
            app.grayButton2.Value = true;
            app.autumnButton2 = uiradiobutton(app.M0ColorMapButtonGroup);
            app.autumnButton2.Text = 'autumn';
            app.autumnButton2.Interpreter = 'html';
            app.winterButton2 = uiradiobutton(app.M0ColorMapButtonGroup);
            app.winterButton2.Text = 'winter';
            app.winterButton2.Interpreter = 'html';
            app.parulaButton2 = uiradiobutton(app.M0ColorMapButtonGroup);
            app.parulaButton2.Text = 'parula';
            app.parulaButton2.Interpreter = 'html';
            app.naviaButton2 = uiradiobutton(app.M0ColorMapButtonGroup);
            app.naviaButton2.Text = 'navia';
            app.naviaButton2.Interpreter = 'html';
            app.R2ColorMapButtonGroup = uibuttongroup(app.M0R2Tab);
            app.R2ColorMapButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @R2ColorMapButtonGroupSelectionChanged, true);
            app.R2ColorMapButtonGroup.Title = 'Color map';
            app.grayButton3 = uiradiobutton(app.R2ColorMapButtonGroup);
            app.grayButton3.Text = 'gray';
            app.grayButton3.Interpreter = 'html';
            app.autumnButton3 = uiradiobutton(app.R2ColorMapButtonGroup);
            app.autumnButton3.Text = 'autumn';
            app.autumnButton3.Interpreter = 'html';
            app.autumnButton3.Value = true;
            app.winterButton3 = uiradiobutton(app.R2ColorMapButtonGroup);
            app.winterButton3.Text = 'winter';
            app.winterButton3.Interpreter = 'html';
            app.parulaButton3 = uiradiobutton(app.R2ColorMapButtonGroup);
            app.parulaButton3.Text = 'parula';
            app.parulaButton3.Interpreter = 'html';
            app.naviaButton3 = uiradiobutton(app.R2ColorMapButtonGroup);
            app.naviaButton3.Text = 'navia';
            app.naviaButton3.Interpreter = 'html';
            app.M0Image = uiimage(app.M0R2Tab);
            app.M0Image.ImageClickedFcn = createCallbackFcn(app, @M0ImageClicked, true);
            app.R2Image = uiimage(app.M0R2Tab);
            app.M0ROIbutton = uibutton(app.M0R2Tab, 'push');
            app.M0ROIbutton.ButtonPushedFcn = createCallbackFcn(app, @ROIbuttonButtonPushed, true);
            app.M0ROIbutton.Tooltip = {'Cross-hair / ROI drawing'};
            app.M0ROIbutton.Text = 'ROI';
            app.M0EraseButton = uibutton(app.M0R2Tab, 'push');
            app.M0EraseButton.ButtonPushedFcn = createCallbackFcn(app, @EraseButtonPushed, true);
            app.M0EraseButton.Visible = 'off';
            app.M0EraseButton.Tooltip = {'Erase ROI'};
            app.M0EraseButton.Text = 'Erase';
            app.M0BrushEditFieldLabel = uilabel(app.M0R2Tab);
            app.M0BrushEditFieldLabel.Visible = 'off';
            app.M0BrushEditFieldLabel.Text = 'brush';
            app.M0BrushEditField = uieditfield(app.M0R2Tab, 'numeric');
            app.M0BrushEditField.Limits = [1 5];
            app.M0BrushEditField.RoundFractionalValues = 'on';
            app.M0BrushEditField.ValueDisplayFormat = '%.0f';
            app.M0BrushEditField.ValueChangedFcn = createCallbackFcn(app, @M0BrushEditFieldValueChanged, true);
            app.M0BrushEditField.Visible = 'off';
            app.M0BrushEditField.Tooltip = {'ROI brush size (pixels)'};
            app.M0BrushEditField.Value = 2;
            app.TabGroup2 = uitabgroup(app.ADCmappUIFigure);
            app.RawImagesTab = uitab(app.TabGroup2);
            app.RawImagesTab.Title = 'Raw images';
            app.RawDataFig = uiaxes(app.RawImagesTab);
            app.RawDataFig.XTick = [];
            app.RawDataFig.YTick = [];
            app.ImageLabel = uilabel(app.RawImagesTab);
            app.ImageLabel.Text = 'Image';
            app.ImageSelectionPanel = uipanel(app.RawImagesTab);
            app.ImageSelectionPanel.Title = 'Image selection';
            app.SlicesEditField = uieditfield(app.ImageSelectionPanel, 'numeric');
            app.SlicesEditField.Limits = [1 Inf];
            app.SlicesEditField.RoundFractionalValues = 'on';
            app.SlicesEditField.ValueDisplayFormat = '%.0f';
            app.SlicesEditField.ValueChangedFcn = createCallbackFcn(app, @SlicesEditFieldValueChanged, true);
            app.SlicesEditField.Tooltip = {'Slice number'};
            app.SlicesEditField.Value = 1;
            app.SlicesSlider = uislider(app.ImageSelectionPanel);
            app.SlicesSlider.Limits = [1 200];
            app.SlicesSlider.MajorTicks = [];
            app.SlicesSlider.ValueChangedFcn = createCallbackFcn(app, @SlicesSliderValueChanged, true);
            app.SlicesSlider.MinorTicks = [1 10 19 28 37 46 55 64 73 82 91 100 109 118 127 136 145 154 163 172 181 190 200];
            app.SlicesSlider.Tooltip = {'Slice number slider'};
            app.SlicesSlider.Value = 1;
            app.ImageEditField = uieditfield(app.ImageSelectionPanel, 'numeric');
            app.ImageEditField.Limits = [1 Inf];
            app.ImageEditField.RoundFractionalValues = 'on';
            app.ImageEditField.ValueDisplayFormat = '%.0f';
            app.ImageEditField.ValueChangedFcn = createCallbackFcn(app, @SlicesEditFieldValueChanged, true);
            app.ImageEditField.Tooltip = {'Image number'};
            app.ImageEditField.Value = 1;
            app.ImageSlider = uislider(app.ImageSelectionPanel);
            app.ImageSlider.Limits = [1 200];
            app.ImageSlider.MajorTicks = [];
            app.ImageSlider.ValueChangedFcn = createCallbackFcn(app, @ImageSliderValueChanged, true);
            app.ImageSlider.MinorTicks = [1 10 19 28 37 46 55 64 73 82 91 100 109 118 127 136 145 154 163 172 181 190 200];
            app.ImageSlider.Tooltip = {'Image number slider'};
            app.ImageSlider.Value = 1;
            app.SlicesEditFieldLabel = uilabel(app.ImageSelectionPanel);
            app.SlicesEditFieldLabel.Text = 'slice';
            app.ImageEditFieldLabel = uilabel(app.ImageSelectionPanel);
            app.ImageEditFieldLabel.Text = 'image';
            app.MaskSettingsPanel = uipanel(app.RawImagesTab);
            app.MaskSettingsPanel.Title = 'Mask settings';
            app.ThresholdSlider = uislider(app.MaskSettingsPanel);
            app.ThresholdSlider.Limits = [1 16384];
            app.ThresholdSlider.MajorTicks = [];
            app.ThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @ThresholdSliderValueChanged, true);
            app.ThresholdSlider.MinorTicks = [1 1001 2001 3001 4001 5001 6001 7001 8001 9001 10001 11001 12001 13001 14001 15001 16384];
            app.ThresholdSlider.Tooltip = {'Mask threshold slider'};
            app.ThresholdSlider.Value = 1;
            app.ErodeButton = uibutton(app.MaskSettingsPanel, 'push');
            app.ErodeButton.ButtonPushedFcn = createCallbackFcn(app, @ErodeButtonPushed, true);
            app.ErodeButton.Tooltip = {'Mask erosion'};
            app.ErodeButton.Text = 'Erode';
            app.DilateButton = uibutton(app.MaskSettingsPanel, 'push');
            app.DilateButton.ButtonPushedFcn = createCallbackFcn(app, @DilateButtonPushed, true);
            app.DilateButton.Tooltip = {'Mask dilation'};
            app.DilateButton.Text = 'Dilate';
            app.CloseButton = uibutton(app.MaskSettingsPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Tooltip = {'Mask closing'};
            app.CloseButton.Text = 'Close';
            app.OpenButton = uibutton(app.MaskSettingsPanel, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Tooltip = {'Mask opening'};
            app.OpenButton.Text = 'Open';
            app.UndoButton = uibutton(app.MaskSettingsPanel, 'push');
            app.UndoButton.ButtonPushedFcn = createCallbackFcn(app, @UndoButtonPushed, true);
            app.UndoButton.Tooltip = {'Undo the last erode, dilate, close or open'};
            app.UndoButton.Text = 'Undo';
            app.MaskSizeEditField = uieditfield(app.MaskSettingsPanel, 'numeric');
            app.MaskSizeEditField.Limits = [1 10];
            app.MaskSizeEditField.RoundFractionalValues = 'on';
            app.MaskSizeEditField.ValueDisplayFormat = '%.0f';
            app.MaskSizeEditField.Tooltip = {'Size of erode, dilate, close and open, in pixels'};
            app.MaskSizeEditField.Value = 1;
            app.ThresholdEditFieldLabel = uilabel(app.MaskSettingsPanel);
            app.ThresholdEditFieldLabel.Text = 'threshold';
            app.ThresholdEditField = uieditfield(app.MaskSettingsPanel, 'numeric');
            app.ThresholdEditField.Limits = [0 Inf];
            app.ThresholdEditField.ValueDisplayFormat = '%.0f';
            app.ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditFieldValueChanged, true);
            app.ThresholdEditField.Tooltip = {'Mask threshold'};
            app.ImageWindowLevelPanel = uipanel(app.RawImagesTab);
            app.ImageWindowLevelPanel.Title = 'Image window/level';
            app.WindowEditField = uieditfield(app.ImageWindowLevelPanel, 'numeric');
            app.WindowEditField.Limits = [0 Inf];
            app.WindowEditField.ValueDisplayFormat = '%.0f';
            app.WindowEditField.ValueChangedFcn = createCallbackFcn(app, @WindowEditFieldValueChanged, true);
            app.WindowEditField.Tooltip = {'Window'};
            app.WindowSlider = uislider(app.ImageWindowLevelPanel);
            app.WindowSlider.Limits = [0 32768];
            app.WindowSlider.MajorTicks = [];
            app.WindowSlider.ValueChangedFcn = createCallbackFcn(app, @WindowSliderValueChanged, true);
            app.WindowSlider.MinorTicks = [0 2048 4096 6144 8192 10240 12288 14336 16384 18432 20480 22528 24576 26624 28672 30720 32768];
            app.WindowSlider.Tooltip = {'Window'};
            app.WindowSlider.Value = 16384;
            app.LevelEditField = uieditfield(app.ImageWindowLevelPanel, 'numeric');
            app.LevelEditField.Limits = [0 Inf];
            app.LevelEditField.ValueDisplayFormat = '%.0f';
            app.LevelEditField.ValueChangedFcn = createCallbackFcn(app, @LevelEditFieldValueChanged, true);
            app.LevelEditField.Tooltip = {'Level'};
            app.LevelSlider = uislider(app.ImageWindowLevelPanel);
            app.LevelSlider.Limits = [0 32768];
            app.LevelSlider.MajorTicks = [];
            app.LevelSlider.ValueChangedFcn = createCallbackFcn(app, @LevelSliderValueChanged, true);
            app.LevelSlider.MinorTicks = [0 2048 4096 6144 8192 10240 12288 14336 16384 18432 20480 22528 24576 26624 28672 30720 32768];
            app.LevelSlider.Tooltip = {'Level'};
            app.LevelSlider.Value = 16384;
            app.LevelEditFieldLabel = uilabel(app.ImageWindowLevelPanel);
            app.LevelEditFieldLabel.Text = 'L';
            app.WindowEditFieldLabel = uilabel(app.ImageWindowLevelPanel);
            app.WindowEditFieldLabel.Text = 'W';
            app.AutoLWButton = uibutton(app.ImageWindowLevelPanel, 'state');
            app.AutoLWButton.ValueChangedFcn = createCallbackFcn(app, @AutoLWButtonValueChanged, true);
            app.AutoLWButton.Tooltip = {'Automatic window and level setting'};
            app.AutoLWButton.Text = 'Auto';
            app.AutoLWButton.Value = true;
            app.RawImage = uiimage(app.RawImagesTab);
            app.BvalueViewFieldLabel = uilabel(app.RawImagesTab);
            app.BvalueViewFieldLabel.Text = 'b-value';
            app.BvalueViewField = uieditfield(app.RawImagesTab, 'text');
            app.BvalueViewField.Editable = 'off';
            app.BvalueViewField.Tooltip = {'Diffusion weighted b-value'};
            app.GradViewFieldLabel = uilabel(app.RawImagesTab);
            app.GradViewFieldLabel.Text = 'grad';
            app.GradViewField = uieditfield(app.RawImagesTab, 'text');
            app.GradViewField.Editable = 'off';
            app.GradViewField.Tooltip = {'Gradient directions'};
            app.ReconstructionTab = uitab(app.TabGroup2);
            app.ReconstructionTab.Title = 'Reco parameters';
            app.EPIRampSamplingCheckBox = uicheckbox(app.ReconstructionTab);
            app.EPIRampSamplingCheckBox.Tooltip = {'Check to put the samples taken on the readout gradient ramps onto the k-space grid, rather than keeping only the plateau'};
            app.EPIRampSamplingCheckBox.Text = 'ramp sampling';
            app.EPIPOCSCheckBox = uicheckbox(app.ReconstructionTab);
            app.EPIPOCSCheckBox.Tooltip = {'Check to fill the k-space lines a partial Fourier EPI did not measure, which sharpens the image and costs signal to noise ratio'};
            app.EPIPOCSCheckBox.Text = 'POCS';
            app.EPIPOCSCheckBox.Value = true;
            app.EPIdistortionCheckBox = uicheckbox(app.ReconstructionTab);
            app.EPIdistortionCheckBox.Tooltip = {'Check to correct the EPI geometric distortion, where the study holds a scan of the same volume with the phase encoding reversed'};
            app.EPIdistortionCheckBox.Text = 'distortion';
            app.EPIdistortionCheckBox.Value = true;
            app.EPIregistrationCheckBox = uicheckbox(app.ReconstructionTab);
            app.EPIregistrationCheckBox.Tooltip = {'Check to put the dynamics of the series in the same place, measured against the volumes without diffusion weighting or against the first dynamic'};
            app.EPIregistrationCheckBox.Text = 'registration';
            app.EPIregistrationCheckBox.Value = true;
            app.EPILabel = uilabel(app.ReconstructionTab);
            app.EPILabel.Tooltip = {'The corrections the MRD reconstruction applies'};
            app.EPILabel.Text = 'EPI';
            app.FitParametersTab = uitab(app.TabGroup2);
            app.FitParametersTab.Title = 'Fit parameters';
            app.SliceSelectionPanel = uipanel(app.FitParametersTab);
            app.SliceSelectionPanel.Title = 'Slice selection';
            app.FirstSliceEditFieldLabel = uilabel(app.SliceSelectionPanel);
            app.FirstSliceEditFieldLabel.Text = 'first slice';
            app.LastSliceEditFieldLabel = uilabel(app.SliceSelectionPanel);
            app.LastSliceEditFieldLabel.Text = 'last slice';
            app.FirstSliceEditField = uieditfield(app.SliceSelectionPanel, 'numeric');
            app.FirstSliceEditField.Limits = [1 200];
            app.FirstSliceEditField.RoundFractionalValues = 'on';
            app.FirstSliceEditField.ValueChangedFcn = createCallbackFcn(app, @FirstSliceEditFieldValueChanged, true);
            app.FirstSliceEditField.Enable = 'off';
            app.FirstSliceEditField.Tooltip = {'First fit slice'};
            app.FirstSliceEditField.Value = 1;
            app.LastSliceEditField = uieditfield(app.SliceSelectionPanel, 'numeric');
            app.LastSliceEditField.Limits = [1 200];
            app.LastSliceEditField.RoundFractionalValues = 'on';
            app.LastSliceEditField.ValueChangedFcn = createCallbackFcn(app, @FirstSliceEditFieldValueChanged, true);
            app.LastSliceEditField.Enable = 'off';
            app.LastSliceEditField.Tooltip = {'Last fit slice'};
            app.LastSliceEditField.Value = 1;
            app.GoodnessOfFitPanel = uipanel(app.FitParametersTab);
            app.GoodnessOfFitPanel.Title = 'Goodness of fit';
            app.Rsquare = uieditfield(app.GoodnessOfFitPanel, 'numeric');
            app.Rsquare.Limits = [0 1];
            app.Rsquare.ValueDisplayFormat = '%.2f';
            app.Rsquare.Enable = 'off';
            app.Rsquare.Tooltip = {'R-square fit threshold'};
            app.Rsquare.Value = 0.8;
            app.Threshold = uicheckbox(app.GoodnessOfFitPanel);
            app.Threshold.Tooltip = {'Check to use the R-square fit threshold'};
            app.Threshold.Enable = 'off';
            app.Threshold.Text = '';
            app.RsquareLabel = uilabel(app.GoodnessOfFitPanel);
            app.RsquareLabel.Text = 'R-square';
            app.ThresholdLabel = uilabel(app.GoodnessOfFitPanel);
            app.ThresholdLabel.Tooltip = {'Check to use the R-square fit threshold'};
            app.ThresholdLabel.Text = 'threshold';
            app.BvalueGradientDirectionSelectionPanel = uipanel(app.FitParametersTab);
            app.BvalueGradientDirectionSelectionPanel.Title = 'B-value / gradient direction selection';
            app.CheckBox_1 = uicheckbox(app.BvalueGradientDirectionSelectionPanel);
            app.CheckBox_1.Text = '1';
            app.CheckBox_1.Value = true;
            app.CheckBox_2 = uicheckbox(app.BvalueGradientDirectionSelectionPanel);
            app.CheckBox_2.Text = '2';
            app.CheckBox_2.Value = true;
            app.CheckBox_3 = uicheckbox(app.BvalueGradientDirectionSelectionPanel);
            app.CheckBox_3.Text = '3';
            app.CheckBox_3.Value = true;
            app.CheckBox_4 = uicheckbox(app.BvalueGradientDirectionSelectionPanel);
            app.CheckBox_4.Text = '4';
            app.CheckBox_4.Value = true;
            app.CheckBox_5 = uicheckbox(app.BvalueGradientDirectionSelectionPanel);
            app.CheckBox_5.Text = '5';
            app.CheckBox_5.Value = true;
            app.CheckBox_6 = uicheckbox(app.BvalueGradientDirectionSelectionPanel);
            app.CheckBox_6.Text = '6';
            app.CheckBox_6.Value = true;
            app.CheckBox_7 = uicheckbox(app.BvalueGradientDirectionSelectionPanel);
            app.CheckBox_7.Text = '7';
            app.CheckBox_7.Value = true;
            app.CheckBox_8 = uicheckbox(app.BvalueGradientDirectionSelectionPanel);
            app.CheckBox_8.Text = '8';
            app.CheckBox_8.Value = true;
            app.CheckBox_9 = uicheckbox(app.BvalueGradientDirectionSelectionPanel);
            app.CheckBox_9.Text = '9';
            app.CheckBox_9.Value = true;
            app.CheckBox_10 = uicheckbox(app.BvalueGradientDirectionSelectionPanel);
            app.CheckBox_10.Text = '10';
            app.CheckBox_10.Value = true;
            app.CheckBox_11 = uicheckbox(app.BvalueGradientDirectionSelectionPanel);
            app.CheckBox_11.Text = '11';
            app.CheckBox_11.Value = true;
            app.CheckBox_12 = uicheckbox(app.BvalueGradientDirectionSelectionPanel);
            app.CheckBox_12.Text = '12';
            app.CheckBox_12.Value = true;
            app.CheckBox_13 = uicheckbox(app.BvalueGradientDirectionSelectionPanel);
            app.CheckBox_13.Text = '13';
            app.CheckBox_13.Value = true;
            app.CheckBox_14 = uicheckbox(app.BvalueGradientDirectionSelectionPanel);
            app.CheckBox_14.Text = '14';
            app.CheckBox_14.Value = true;
            app.CheckBox_15 = uicheckbox(app.BvalueGradientDirectionSelectionPanel);
            app.CheckBox_15.Text = '15';
            app.CheckBox_15.Value = true;
            app.CheckBox_16 = uicheckbox(app.BvalueGradientDirectionSelectionPanel);
            app.CheckBox_16.Text = '16';
            app.CheckBox_16.Value = true;
            app.CheckBox_17 = uicheckbox(app.BvalueGradientDirectionSelectionPanel);
            app.CheckBox_17.Text = '17';
            app.CheckBox_17.Value = true;
            app.CheckBox_18 = uicheckbox(app.BvalueGradientDirectionSelectionPanel);
            app.CheckBox_18.Text = '18';
            app.CheckBox_18.Value = true;
            app.CheckBox_19 = uicheckbox(app.BvalueGradientDirectionSelectionPanel);
            app.CheckBox_19.Text = '19';
            app.CheckBox_19.Value = true;
            app.CheckBox_20 = uicheckbox(app.BvalueGradientDirectionSelectionPanel);
            app.CheckBox_20.Text = '20';
            app.CheckBox_20.Value = true;
            app.CheckBox_21 = uicheckbox(app.BvalueGradientDirectionSelectionPanel);
            app.CheckBox_21.Text = '21';
            app.CheckBox_21.Value = true;
            app.CheckBox_22 = uicheckbox(app.BvalueGradientDirectionSelectionPanel);
            app.CheckBox_22.Text = '22';
            app.CheckBox_22.Value = true;
            app.CheckBox_23 = uicheckbox(app.BvalueGradientDirectionSelectionPanel);
            app.CheckBox_23.Text = '23';
            app.CheckBox_23.Value = true;
            app.CheckBox_24 = uicheckbox(app.BvalueGradientDirectionSelectionPanel);
            app.CheckBox_24.Text = '24';
            app.CheckBox_24.Value = true;
            app.CheckBox_25 = uicheckbox(app.BvalueGradientDirectionSelectionPanel);
            app.CheckBox_25.Text = '25';
            app.CheckBox_25.Value = true;
            app.CheckBox_26 = uicheckbox(app.BvalueGradientDirectionSelectionPanel);
            app.CheckBox_26.Text = '26';
            app.CheckBox_26.Value = true;
            app.CheckBox_27 = uicheckbox(app.BvalueGradientDirectionSelectionPanel);
            app.CheckBox_27.Text = '27';
            app.CheckBox_27.Value = true;
            app.CheckBox_28 = uicheckbox(app.BvalueGradientDirectionSelectionPanel);
            app.CheckBox_28.Text = '28';
            app.CheckBox_28.Value = true;
            app.CheckBox_29 = uicheckbox(app.BvalueGradientDirectionSelectionPanel);
            app.CheckBox_29.Text = '29';
            app.CheckBox_29.Value = true;
            app.CheckBox_30 = uicheckbox(app.BvalueGradientDirectionSelectionPanel);
            app.CheckBox_30.Text = '30';
            app.CheckBox_30.Value = true;
            app.CheckBox_31 = uicheckbox(app.BvalueGradientDirectionSelectionPanel);
            app.CheckBox_31.Text = '31';
            app.CheckBox_31.Value = true;
            app.CheckBox_32 = uicheckbox(app.BvalueGradientDirectionSelectionPanel);
            app.CheckBox_32.Text = '32';
            app.CheckBox_32.Value = true;
            app.CheckBox_33 = uicheckbox(app.BvalueGradientDirectionSelectionPanel);
            app.CheckBox_33.Text = '33';
            app.CheckBox_33.Value = true;
            app.CheckBox_34 = uicheckbox(app.BvalueGradientDirectionSelectionPanel);
            app.CheckBox_34.Text = '34';
            app.CheckBox_34.Value = true;
            app.CheckBox_35 = uicheckbox(app.BvalueGradientDirectionSelectionPanel);
            app.CheckBox_35.Text = '35';
            app.CheckBox_35.Value = true;
            app.CheckBox_36 = uicheckbox(app.BvalueGradientDirectionSelectionPanel);
            app.CheckBox_36.Text = '36';
            app.CheckBox_36.Value = true;
            app.CheckBox_37 = uicheckbox(app.BvalueGradientDirectionSelectionPanel);
            app.CheckBox_37.Text = '37';
            app.CheckBox_37.Value = true;
            app.CheckBox_38 = uicheckbox(app.BvalueGradientDirectionSelectionPanel);
            app.CheckBox_38.Text = '38';
            app.CheckBox_38.Value = true;
            app.CheckBox_39 = uicheckbox(app.BvalueGradientDirectionSelectionPanel);
            app.CheckBox_39.Text = '39';
            app.CheckBox_39.Value = true;
            app.CheckBox_40 = uicheckbox(app.BvalueGradientDirectionSelectionPanel);
            app.CheckBox_40.Text = '40';
            app.CheckBox_40.Value = true;
            app.CheckBox_41 = uicheckbox(app.BvalueGradientDirectionSelectionPanel);
            app.CheckBox_41.Text = '41';
            app.CheckBox_41.Value = true;
            app.CheckBox_42 = uicheckbox(app.BvalueGradientDirectionSelectionPanel);
            app.CheckBox_42.Text = '42';
            app.CheckBox_42.Value = true;
            app.CheckBox_43 = uicheckbox(app.BvalueGradientDirectionSelectionPanel);
            app.CheckBox_43.Text = '43';
            app.CheckBox_43.Value = true;
            app.CheckBox_44 = uicheckbox(app.BvalueGradientDirectionSelectionPanel);
            app.CheckBox_44.Text = '44';
            app.CheckBox_44.Value = true;
            app.CheckBox_45 = uicheckbox(app.BvalueGradientDirectionSelectionPanel);
            app.CheckBox_45.Text = '45';
            app.CheckBox_45.Value = true;
            app.CheckBox_46 = uicheckbox(app.BvalueGradientDirectionSelectionPanel);
            app.CheckBox_46.Text = '46';
            app.CheckBox_46.Value = true;
            app.CheckBox_47 = uicheckbox(app.BvalueGradientDirectionSelectionPanel);
            app.CheckBox_47.Text = '47';
            app.CheckBox_47.Value = true;
            app.CheckBox_48 = uicheckbox(app.BvalueGradientDirectionSelectionPanel);
            app.CheckBox_48.Text = '48';
            app.CheckBox_48.Value = true;
            app.CheckBox_49 = uicheckbox(app.BvalueGradientDirectionSelectionPanel);
            app.CheckBox_49.Text = '49';
            app.CheckBox_49.Value = true;
            app.CheckBox_50 = uicheckbox(app.BvalueGradientDirectionSelectionPanel);
            app.CheckBox_50.Text = '50';
            app.CheckBox_50.Value = true;
            app.CheckBox_51 = uicheckbox(app.BvalueGradientDirectionSelectionPanel);
            app.CheckBox_51.Text = '51';
            app.CheckBox_51.Value = true;
            app.CheckBox_52 = uicheckbox(app.BvalueGradientDirectionSelectionPanel);
            app.CheckBox_52.Text = '52';
            app.CheckBox_52.Value = true;
            app.CheckBox_53 = uicheckbox(app.BvalueGradientDirectionSelectionPanel);
            app.CheckBox_53.Text = '53';
            app.CheckBox_53.Value = true;
            app.CheckBox_54 = uicheckbox(app.BvalueGradientDirectionSelectionPanel);
            app.CheckBox_54.Text = '54';
            app.CheckBox_54.Value = true;
            app.CheckBox_55 = uicheckbox(app.BvalueGradientDirectionSelectionPanel);
            app.CheckBox_55.Text = '55';
            app.CheckBox_55.Value = true;
            app.CheckBox_56 = uicheckbox(app.BvalueGradientDirectionSelectionPanel);
            app.CheckBox_56.Text = '56';
            app.CheckBox_56.Value = true;
            app.CheckBox_57 = uicheckbox(app.BvalueGradientDirectionSelectionPanel);
            app.CheckBox_57.Text = '57';
            app.CheckBox_57.Value = true;
            app.CheckBox_58 = uicheckbox(app.BvalueGradientDirectionSelectionPanel);
            app.CheckBox_58.Text = '58';
            app.CheckBox_58.Value = true;
            app.CheckBox_59 = uicheckbox(app.BvalueGradientDirectionSelectionPanel);
            app.CheckBox_59.Text = '59';
            app.CheckBox_59.Value = true;
            app.CheckBox_60 = uicheckbox(app.BvalueGradientDirectionSelectionPanel);
            app.CheckBox_60.Text = '60';
            app.CheckBox_60.Value = true;
            app.CheckBox_61 = uicheckbox(app.BvalueGradientDirectionSelectionPanel);
            app.CheckBox_61.Text = '61';
            app.CheckBox_61.Value = true;
            app.CheckBox_62 = uicheckbox(app.BvalueGradientDirectionSelectionPanel);
            app.CheckBox_62.Text = '62';
            app.CheckBox_62.Value = true;
            app.CheckBox_63 = uicheckbox(app.BvalueGradientDirectionSelectionPanel);
            app.CheckBox_63.Text = '63';
            app.CheckBox_63.Value = true;
            app.CheckBox_64 = uicheckbox(app.BvalueGradientDirectionSelectionPanel);
            app.CheckBox_64.Text = '64';
            app.CheckBox_64.Value = true;
            app.UserGradientTablePanel = uipanel(app.FitParametersTab);
            app.UserGradientTablePanel.Title = 'User gradient table';
            app.ImportGradTableButton = uibutton(app.UserGradientTablePanel, 'push');
            app.ImportGradTableButton.ButtonPushedFcn = createCallbackFcn(app, @ImportGradTableButtonPushed, true);
            app.ImportGradTableButton.Tooltip = {'Import gradient table'};
            app.ImportGradTableButton.Text = 'Import';
            app.AppSettingsTab = uitab(app.TabGroup2);
            app.AppSettingsTab.Title = 'App settings';
            app.SoundsLabel = uilabel(app.AppSettingsTab);
            app.SoundsLabel.Text = 'Sounds';
            app.AutoSoundsCheckBox = uicheckbox(app.AppSettingsTab);
            app.AutoSoundsCheckBox.Tooltip = {'Warning and error sounds on or off'};
            app.AutoSoundsCheckBox.Text = '';
            app.AutoSoundsCheckBox.Value = true;
            app.AutoSoundsCheckBoxLabel = uilabel(app.AppSettingsTab);
            app.AutoSoundsCheckBoxLabel.Tooltip = {'Warning and error sounds on or off'};
            app.AutoSoundsCheckBoxLabel.Text = 'sounds on/off';
            app.ColorsLabel = uilabel(app.AppSettingsTab);
            app.ColorsLabel.Text = 'Colors';
            app.ColorsButtonGroup = uibuttongroup(app.AppSettingsTab);
            app.ColorsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ColorsButtonGroupSelectionChanged, true);
            app.ColorsButtonGroup.Tooltip = {'App color scheme selection'};
            app.ClassicButton = uiradiobutton(app.ColorsButtonGroup);
            app.ClassicButton.Text = 'classic';
            app.ClassicButton.Interpreter = 'html';
            app.BlackWhiteButton = uiradiobutton(app.ColorsButtonGroup);
            app.BlackWhiteButton.Text = 'black/white';
            app.BlackWhiteButton.Interpreter = 'html';
            app.BlackWhiteButton.Value = true;
            app.TabGroup4 = uitabgroup(app.ADCmappUIFigure);
            app.SequenceParametersTab = uitab(app.TabGroup4);
            app.SequenceParametersTab.Title = 'Sequence parameters';
            app.TRViewFieldLabel = uilabel(app.SequenceParametersTab);
            app.TRViewFieldLabel.Text = 'TR (ms)';
            app.TRViewField = uieditfield(app.SequenceParametersTab, 'numeric');
            app.TRViewField.Limits = [0 Inf];
            app.TRViewField.Editable = 'off';
            app.TRViewField.Tooltip = {'Repetition time'};
            app.TEViewFieldLabel = uilabel(app.SequenceParametersTab);
            app.TEViewFieldLabel.Text = 'TE (ms)';
            app.TEViewField = uieditfield(app.SequenceParametersTab, 'numeric');
            app.TEViewField.Limits = [0 Inf];
            app.TEViewField.ValueChangedFcn = createCallbackFcn(app, @TEViewFieldValueChanged, true);
            app.TEViewField.Editable = 'off';
            app.TEViewField.Tooltip = {'Echo time'};
            app.dimxViewFieldLabel = uilabel(app.SequenceParametersTab);
            app.dimxViewFieldLabel.Text = 'dimx';
            app.dimxViewField = uieditfield(app.SequenceParametersTab, 'numeric');
            app.dimxViewField.Limits = [0 Inf];
            app.dimxViewField.ValueDisplayFormat = '%.0f';
            app.dimxViewField.Editable = 'off';
            app.dimxViewField.Tooltip = {'Readout matrix size'};
            app.dimyViewFieldLabel = uilabel(app.SequenceParametersTab);
            app.dimyViewFieldLabel.Text = 'dimy';
            app.dimyViewField = uieditfield(app.SequenceParametersTab, 'numeric');
            app.dimyViewField.Limits = [0 Inf];
            app.dimyViewField.ValueDisplayFormat = '%.0f';
            app.dimyViewField.Editable = 'off';
            app.dimyViewField.Tooltip = {'Phase-encoding matrix size'};
            app.SlicesViewFieldLabel = uilabel(app.SequenceParametersTab);
            app.SlicesViewFieldLabel.Text = '#slices';
            app.SlicesViewField = uieditfield(app.SequenceParametersTab, 'numeric');
            app.SlicesViewField.Limits = [0 Inf];
            app.SlicesViewField.ValueDisplayFormat = '%.0f';
            app.SlicesViewField.Editable = 'off';
            app.SlicesViewField.Tooltip = {'Number of slices'};
            app.NRImagesViewFieldLabel = uilabel(app.SequenceParametersTab);
            app.NRImagesViewFieldLabel.Text = '#images';
            app.NRImagesViewField = uieditfield(app.SequenceParametersTab, 'numeric');
            app.NRImagesViewField.Limits = [0 Inf];
            app.NRImagesViewField.Editable = 'off';
            app.NRImagesViewField.Tooltip = {'Number of images'};
            app.FOVViewFieldLabel = uilabel(app.SequenceParametersTab);
            app.FOVViewFieldLabel.Text = 'FOV (mm)';
            app.FOVViewField = uieditfield(app.SequenceParametersTab, 'numeric');
            app.FOVViewField.Limits = [0 Inf];
            app.FOVViewField.Editable = 'off';
            app.FOVViewField.Tooltip = {'Field-of-view'};
            app.SLTViewFieldLabel = uilabel(app.SequenceParametersTab);
            app.SLTViewFieldLabel.Text = 'slice thickness (mm)';
            app.SLTViewField = uieditfield(app.SequenceParametersTab, 'numeric');
            app.SLTViewField.Limits = [0 Inf];
            app.SLTViewField.Editable = 'off';
            app.SLTViewField.Tooltip = {'Slice thickness'};
            app.AspectRatioViewFieldLabel = uilabel(app.SequenceParametersTab);
            app.AspectRatioViewFieldLabel.Text = 'aspect ratio';
            app.AspectRatioViewField = uieditfield(app.SequenceParametersTab, 'numeric');
            app.AspectRatioViewField.Limits = [0.25 4];
            app.AspectRatioViewField.ValueDisplayFormat = '%.1f';
            app.AspectRatioViewField.Editable = 'off';
            app.AspectRatioViewField.Tooltip = {'Image aspect ratio'};
            app.AspectRatioViewField.Value = 1;
            app.DicomDirViewFieldLabel = uilabel(app.SequenceParametersTab);
            app.DicomDirViewFieldLabel.Text = 'datafolder';
            app.DicomDirViewField = uieditfield(app.SequenceParametersTab, 'text');
            app.DicomDirViewField.Editable = 'off';
            app.DicomDirViewField.Tooltip = {'Data file or folder name'};
            app.Hyperlink = uihyperlink(app.ADCmappUIFigure);
            app.Hyperlink.URL = 'https://github.com/Moby1971/';
            app.Hyperlink.Text = 'GitHub';
            % Show the figure after all components are created
            app.ADCmappUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ADCmapp

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ADCmappUIFigure)

                % Execute the startup function
                runStartupFcn(app, @StartupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.ADCmappUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ADCmappUIFigure)
        end
    end
end